classdef AdvancedControls < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CloseAdvancedControllerButton  matlab.ui.control.Button
        AdvancedContollerLabel         matlab.ui.control.Label
        InclinometerComp               InclinometerComp
        SetCOMs                        SetCOMs
        ArduinoControllerComp          ArduinoControllerComp
        ForceGaugeComp                 ForceGaugeComp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.05;
            bottom = screenHeight*0.057;
            width = screenWidth*0.6;
            height = screenHeight*0.75;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: CloseAdvancedControllerButton
        function CloseAdvancedControllerButtonPushed(app, event)
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForceGaugeComp = ForceGaugeComp(app.UIFigure);
            app.ArduinoControllerComp = ArduinoControllerComp(app.UIFigure);
            app.SetCOMs = SetCOMs(app.UIFigure);
            app.InclinometerComp = InclinometerComp(app.UIFigure);
            app.AdvancedContollerLabel = uilabel(app.UIFigure);
            app.AdvancedContollerLabel.Text = 'Advanced Contoller';
            app.CloseAdvancedControllerButton = uibutton(app.UIFigure, 'push');
            app.CloseAdvancedControllerButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAdvancedControllerButtonPushed, true);
            app.CloseAdvancedControllerButton.Text = 'Close Advanced Controller';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdvancedControls

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end