classdef ArduinoControllerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ArduinoControllerPanel   matlab.ui.container.Panel
        ResetArduinoButton       matlab.ui.control.Button
        TestIntervalEditField    matlab.ui.control.EditField
        SetTestIntervalmmButton  matlab.ui.control.Button
    end

    
    methods (Access = public)
        function ret_signal = reset_arduino(comp)
            s = evalin('base', 'arduino_serial');
            RESET_ARDUINO = 16;
            serial_string = strcat(num2str(RESET_ARDUINO),",0,0");
            custom_write(s, serial_string);
            ret_signal = custom_read(s);
            flush(s);
        end

        function sig = serial_communication(comp, s, message)
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
        end

        function custom_write(comp, s, message)
            flush(s);
            writeline(s, message);
        end
        
        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetArduinoButton
        function ResetArduinoButtonPushed(comp, event)
            try
                reset_arduino(comp);
                str = "The arduino has been reset. This also sets the start position of the ski stepper motor to current position of the ski incinometer.";
                fig = uifigure;
                uialert(fig,str,'Success Message','Icon','success');
            catch e
                str = getReport(e,"extended");
                fig = uifigure;
                uialert(fig,str,'Arduino Controller Error');
            end
        end

        % Button pushed function: SetTestIntervalmmButton
        function SetTestIntervalmmButtonPushed(comp, event)
            try
                test_interval_input = str2double(comp.TestIntervalEditField.Value);
                if test_interval_input <= 0 || test_interval_input > 76
                   str = strcat("Please input test interval larger than 0mm and less than 76mm 100 and 2000. Inputed: ", num2str(test_interval_input));
                   fig = uifigure;
                   uialert(fig,str,'Inclinometer Controller Error');
                   return;
                end 
                test_interval = ceil(test_interval_input);
                assignin('base', "test_interval_mm", test_interval);
                str = strcat("Test interval has been set to set: ", num2str(test_interval)," mm");
                fig = uifigure;
                uialert(fig,str,'Success Message','Icon','success');
            catch e
                fig = uifigure;
                m = getReport(e,"extended");
                uialert(fig,m,'Arduino Controller Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 292 132];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ArduinoControllerPanel = uipanel(comp);
            comp.ArduinoControllerPanel.Title = 'Arduino Controller';
            comp.SetTestIntervalmmButton = uibutton(comp.ArduinoControllerPanel, 'push');
            comp.SetTestIntervalmmButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetTestIntervalmmButtonPushed, true);
            comp.SetTestIntervalmmButton.Text = 'Set Test Interval (mm)';
            comp.TestIntervalEditField = uieditfield(comp.ArduinoControllerPanel, 'text');
            comp.TestIntervalEditField.Placeholder = 'Enter Test Interval';
            comp.ResetArduinoButton = uibutton(comp.ArduinoControllerPanel, 'push');
            comp.ResetArduinoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetArduinoButtonPushed, true);
            comp.ResetArduinoButton.Text = 'Reset Arduino';
        end
    end
end