classdef InclinometerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        InclinometerStepperMotorControllerPanel  matlab.ui.container.Panel
        InclinometerSPRDropDown    matlab.ui.control.DropDown
        SetInclinometerMotorStepsSizeButton  matlab.ui.control.Button
        SetInclinometerMotorAccelerationstepssec2Button  matlab.ui.control.Button
        InclinometerMotorAccelerationEditField  matlab.ui.control.EditField
        SetInclinometerMotorMaxSpeedstepssecButton  matlab.ui.control.Button
        InclinometerMotorMaxSpeedEditField  matlab.ui.control.EditField
        MoveToStartButton          matlab.ui.control.Button
        MoveXMillimetersButton     matlab.ui.control.Button
        MoveXMillimetersEditField  matlab.ui.control.EditField
    end

    methods (Access = public)
        function ret_signal = move_x_mm(comp, dis_mm)
            % global app.arduino_serial;
            arduino_serial = evalin('base', 'arduino_serial');
            MOVE_X = 4;
            serial_string = strcat(num2str(MOVE_X),",",num2str(dis_mm));
            %disp(serial_string);
            ret_signal = serial_communication(comp, arduino_serial, serial_string);
            flush(arduino_serial);
            disp(ret_signal);
            % disp('in move x mm');
            % disp(dis_mm);
        end
        function ret_mm = return_to_start(comp)
            arduino_serial = evalin('base', 'arduino_serial');
            MOVE_TO_START = 2;
            serial_string = strcat(num2str(MOVE_TO_START),",0,1");
            ret_mm = serial_communication(comp, arduino_serial, serial_string);
            flush(arduino_serial);
        end
        function sig = serial_communication(comp, s, message)
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
        end

        function custom_write(comp, s, message)
            flush(s);
            writeline(s, message);
        end

        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end
        
        function ret_signal = set_max_speed(comp, max_speed)
            SET_MAX_SPEED = 8;
            % global app.arduino_serial;
            arduino_serial = evalin('base', 'arduino_serial');
            serial_string = strcat(num2str(SET_MAX_SPEED),",",num2str(max_speed),",0");
            %ret_signal = serial_communication(s, serial_string);
            custom_write(comp, arduino_serial, serial_string);
            ret_signal = custom_read(comp, arduino_serial);
            flush(arduino_serial);
        end

        function ret_signal = set_acceleration(comp, acceleration)
            SET_ACCELERATION = 10;
            % global app.arduino_serial;
            arduino_serial = evalin('base', 'arduino_serial');
            serial_string = strcat(num2str(SET_ACCELERATION),",",num2str(acceleration),",0");
            %ret_signal = serial_communication(s, serial_string);
            custom_write(comp, arduino_serial, serial_string);
            ret_signal = custom_read(comp, arduino_serial);
            flush(arduino_serial);
        end

        function ret_signal = set_Step_Size(comp, step_size)
            SET_STEPS_PER_REVOLUTION = 12;
            % global app.arduino_serial;
            arduino_serial = evalin('base', 'arduino_serial');
            serial_string = strcat(num2str(SET_STEPS_PER_REVOLUTION),",",num2str(step_size),",0");
            %ret_signal = serial_communication(s, serial_string);
            custom_write(comp, arduino_serial, serial_string);
            ret_signal = custom_read(comp, arduino_serial);
            disp(ret_signal);
            flush(arduino_serial);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoveXMillimetersButton
        function move_x_milimeters(comp, event)
            try
                milimeters = str2num(comp.MoveXMillimetersEditField.Value);
                disp(milimeters);
                move_x_mm(comp, milimeters);
            catch e
                fig = uifigure;
                m = getReport(e,"extended");
                uialert(fig,m,'Inclinometer Controller Error');
            end
        end

        % Button pushed function: MoveToStartButton
        function move_to_start(comp, event)
            return_to_start(comp);
        end

        % Callback function
        function arduino_reset(comp, event)
            
        end

        % Button pushed function: 
        % SetInclinometerMotorMaxSpeedstepssecButton
        function SetInclinometerMotorMaxSpeedstepssecButtonPushed(comp, event)
            try
                max_speed = str2num(comp.InclinometerMotorMaxSpeedEditField.Value);
                if max_speed < 100 || max_speed > 3500
                   str = strcat("Please input max speed between 100 and 2000. Inputed: ", num2str(max_speed));
                   fig = uifigure;
                   uialert(fig,str,'Inclinometer Controller Error');
                   return;
                end
                set_max_speed(comp, max_speed);
            catch e          
                fig = uifigure;
                m = getReport(e,"extended");
                uialert(fig,m,'Inclinometer Controller Error');
            end
        end

        % Button pushed function: 
        % SetInclinometerMotorAccelerationstepssec2Button
        function SetInclinometerMotorAccelerationstepssec2ButtonPushed(comp, event)
            accel = str2num(comp.InclinometerMotorAccelerationEditField.Value);
            try                
                if (accel < 100) || (accel > 3500)
                   str = strcat("Please input acceleration between 100 and 2000. Inputed: ", num2str(accel));
                   fig = uifigure;
                   uialert(fig,str,'Inclinometer Controller Error');
                   return;
                end
                set_acceleration(comp, accel);
            catch e                
                fig = uifigure;
                m = getReport(e,"extended");
                uialert(fig,m,'Inclinometer Controller Error');
            end
        end

        % Button pushed function: SetInclinometerMotorStepsSizeButton
        function SetInclinometerMotorStepsSizeButtonPushed(comp, event)
            try
                step_size = comp.InclinometerSPRDropDown.Value;
                if convertCharsToStrings(step_size) == convertCharsToStrings(comp.InclinometerSPRDropDown.Placeholder)
                   str = strcat("Please select steps size. Selected: ", num2str(step_size));
                   fig = uifigure;
                   uialert(fig,str,'Inclinometer Controller Error');
                   return;
                end
                set_Step_Size(comp, step_size);
            catch e          
                fig = uifigure;
                m = getReport(e,"extended");
                uialert(fig,m,'Inclinometer Controller Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 437 263];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.InclinometerStepperMotorControllerPanel = uipanel(comp);
            comp.InclinometerStepperMotorControllerPanel.Title = 'Inclinometer Stepper Motor Controller';
            comp.MoveXMillimetersEditField = uieditfield(comp.InclinometerStepperMotorControllerPanel, 'text');
            comp.MoveXMillimetersEditField.Placeholder = 'Negative > back. Positive > front.';
            comp.MoveXMillimetersButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.MoveXMillimetersButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @move_x_milimeters, true);
            comp.MoveXMillimetersButton.Text = 'Move X Millimeters';
            comp.MoveToStartButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.MoveToStartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @move_to_start, true);
            comp.MoveToStartButton.Text = 'Move To Start';
            comp.InclinometerMotorMaxSpeedEditField = uieditfield(comp.InclinometerStepperMotorControllerPanel, 'text');
            comp.InclinometerMotorMaxSpeedEditField.Placeholder = 'Enter Steps';
            comp.SetInclinometerMotorMaxSpeedstepssecButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.SetInclinometerMotorMaxSpeedstepssecButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetInclinometerMotorMaxSpeedstepssecButtonPushed, true);
            comp.SetInclinometerMotorMaxSpeedstepssecButton.Text = 'Set Inclinometer Motor Max Speed (steps/sec)';
            comp.InclinometerMotorAccelerationEditField = uieditfield(comp.InclinometerStepperMotorControllerPanel, 'text');
            comp.InclinometerMotorAccelerationEditField.Placeholder = 'Enter Steps';
            comp.SetInclinometerMotorAccelerationstepssec2Button = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.SetInclinometerMotorAccelerationstepssec2Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetInclinometerMotorAccelerationstepssec2ButtonPushed, true);
            comp.SetInclinometerMotorAccelerationstepssec2Button.Text = 'Set Inclinometer Motor Acceleration (steps/sec^2)';
            comp.SetInclinometerMotorStepsSizeButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.SetInclinometerMotorStepsSizeButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetInclinometerMotorStepsSizeButtonPushed, true);
            comp.SetInclinometerMotorStepsSizeButton.Text = 'Set Inclinometer Motor Steps Size';
            comp.InclinometerSPRDropDown = uidropdown(comp.InclinometerStepperMotorControllerPanel);
            comp.InclinometerSPRDropDown.Items = {'Step Size Must Match Drive Step Size', '1', '2', '4', '8', '16', '32'};
            comp.InclinometerSPRDropDown.Placeholder = 'Step Size Must Match Drive Step Size';
            comp.InclinometerSPRDropDown.Value = 'Step Size Must Match Drive Step Size';
        end
    end
end