classdef NameTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SetTestNameComp          SetTestNameComp
        NavigationPanel          matlab.ui.container.Panel
        SetStartforTestButton    matlab.ui.control.Button
        HomeButton               matlab.ui.control.Button
        SkiTestingNameTestLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin( 'base', 'clear dir_name');
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.05;
            bottom = screenHeight*0.057;
            width = screenWidth*0.4;
            height = screenHeight*0.90;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
            % app.NavigationPanel.Enable = "off";
            % app.SetStartforTestButton.Enable = "off";
        end

        % Callback function
        function HasThisSkiAlreadyBeenTestedSwitchValueChanged(app, event)
            value = app.HasThisSkiAlreadyBeenTestedSwitch.Value;
            if value == "Yes"
                % disable "Name Test" panel
            else
                % disable "Select Existing Test" panel
            end
        end

        % Callback function
        function CustomNameSwitchValueChanged(app, event)
            value = app.CustomNameSwitch.Value;
            if value == "Yes"
                % disable "Standard Name" panel
            else
                % disable "Custom Name" panel
            end
        end

        % Callback function
        function SubmitButton_2Pushed(app, event)
            %get name from NameTextArea
            %if name is empty then do not set dir name (possibly and error message)
            %set directory name to  his name
        end

        % Callback function
        function SubmitButtonPushed(app, event)
            %get name from 
            %   ManufacturerTextArea
            %   ModelNameTextArea
            %   ModelYearTextArea
            %   LengthcmDropDownOpening
            %if all are empty then do not set dir name
            %if lenght is less than 135 cm or greater than 200 cm then do not set dir name
            %concate all the names together that are not empty
            %set directory name to this name
        end

        % Callback function
        function SubmitButton_3Pushed(app, event)
            %get selected name from ExistingTestNamesDropDown
            %if name is the defualt name, then do not set dir name
            %set dir name 
        end

        % Callback function
        function LengthcmDropDownValueChanged(app, event)
            value = app.LengthcmDropDown.Value;
            %make it the showen value ??? not sure if this needed
        end

        % Callback function
        function LengthcmDropDownOpening(app, event)
            %populate option with values between 135 - 200 cm
        end

        % Callback function
        function ExistingTestNamesDropDownOpening(app, event)
            % get all dir names from  data directory
            % populate options with all directory names in the data
            % directory
        end

        % Callback function
        function NextButtonPushed(app, event)
            %go to the UnloadedTest.mlapp of ski test
        end

        % Callback function
        function BackButtonPushed(app, event)
            % go to HomePage.mlapp
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            HomePage;
            closereq();
        end

        % Button pushed function: SetStartforTestButton
        function SetStartforTestButtonPushed(app, event)
            ise_dir_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
            if ise_dir_name
                setStartPosition;
                closereq();
            else
                fig = uifigure;
                uialert(fig,"You must set the test name before testing.",'Name Test Error');
            end
        end

        % Button down function: NavigationPanel
        function NavigationPanelButtonDown(app, event)
            % app.NavigationPanel.Enable = "on";
            %disp("before enable ski button");
            % ise_dir_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
            % %disp(ise_dir_name);
            % if ise_dir_name
            %     %disp("in the enable ski button");
            %     app.SetStartforTestButton.Enable = "on";
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SkiTestingNameTestLabel = uilabel(app.UIFigure);
            app.SkiTestingNameTestLabel.Text = 'Ski Testing - Name Test';
            app.NavigationPanel = uipanel(app.UIFigure);
            app.NavigationPanel.Title = 'Navigation';
            app.NavigationPanel.ButtonDownFcn = createCallbackFcn(app, @NavigationPanelButtonDown, true);
            app.HomeButton = uibutton(app.NavigationPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.SetStartforTestButton = uibutton(app.NavigationPanel, 'push');
            app.SetStartforTestButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartforTestButtonPushed, true);
            app.SetStartforTestButton.Text = 'Set Start for Test';
            app.SetTestNameComp = SetTestNameComp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NameTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end