classdef RunTestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SkiFlexTestingRunTestLabel  matlab.ui.control.Label
        TestingControllerComp       TestingControllerComp
        NavigationPanel             matlab.ui.container.Panel
        AdvancedControllerButton    matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        SetStartforTestButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.05;
            bottom = screenHeight*0.07;
            width = screenWidth*0.37;
            height = screenHeight*0.89;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: SetStartforTestButton
        function SetStartforTestButtonPushed(app, event)
            setStartPosition
            closereq();
        end

        % Button pushed function: AdvancedControllerButton
        function AdvancedControllerButtonPushed(app, event)
            AdvancedControls;
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            ise_dir_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
            test_saved = evalin("base", "test_saved");
            if ise_dir_name && test_saved
                HomePage;
                closereq();
            else
                fig = uifigure;
                uialert(fig,"You must save the test before finishing.",'Run Test Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NavigationPanel = uipanel(app.UIFigure);
            app.NavigationPanel.Title = 'Navigation';
            app.SetStartforTestButton = uibutton(app.NavigationPanel, 'push');
            app.SetStartforTestButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartforTestButtonPushed, true);
            app.SetStartforTestButton.Text = 'Set Start for Test';
            app.FinishButton = uibutton(app.NavigationPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.AdvancedControllerButton = uibutton(app.NavigationPanel, 'push');
            app.AdvancedControllerButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedControllerButtonPushed, true);
            app.AdvancedControllerButton.Text = 'Advanced Controller';
            app.TestingControllerComp = TestingControllerComp(app.UIFigure);
            app.SkiFlexTestingRunTestLabel = uilabel(app.UIFigure);
            app.SkiFlexTestingRunTestLabel.Text = 'Ski Flex Testing - Run Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunTestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end