classdef RunTests < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SkiTestingRunTestLabel    matlab.ui.control.Label
        NavigationPanel           matlab.ui.container.Panel
        AdvancedControllerButton  matlab.ui.control.Button
        FinishButton              matlab.ui.control.Button
        NameTestButton            matlab.ui.control.Button
        TestingControllerComp     TestingControllerComp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % comp.NavigationPanel.Enable = "off";
            % comp.FinishButton.Enable = "off";
        end

        % Button pushed function: NameTestButton
        function NameTestButtonPushed(comp, event)
            NameTest;
            closereq();
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(comp, event)
            ise_dir_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
            test_saved = evalin("base", "test_saved");
            if ise_dir_name && test_saved
                HomePage;
                closereq();
            else
                fig = uifigure;
                uialert(fig,"You must save the test before finishing.",'Run Test Error');
            end
        end

        % Button down function: NavigationPanel
        function NavigationPanelButtonDown(comp, event)
            % comp.NavigationPanel.Enable = "on";
            % test_saved = evalin("base", "test_saved");
            % if test_saved
            %     comp.FinishButton.Enable = "on";
            %     assignin("base", "test_saved", false);
            % end
        end

        % Button pushed function: AdvancedControllerButton
        function AdvancedControllerButtonPushed(comp, event)
            AdvancedControls;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 467 871];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TestingControllerComp = TestingControllerComp(comp);
            comp.NavigationPanel = uipanel(comp);
            comp.NavigationPanel.Title = 'Navigation';
            comp.NavigationPanel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @NavigationPanelButtonDown, true);
            comp.NameTestButton = uibutton(comp.NavigationPanel, 'push');
            comp.NameTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NameTestButtonPushed, true);
            comp.NameTestButton.Text = 'Name Test';
            comp.FinishButton = uibutton(comp.NavigationPanel, 'push');
            comp.FinishButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FinishButtonPushed, true);
            comp.FinishButton.Text = 'Finish';
            comp.AdvancedControllerButton = uibutton(comp.NavigationPanel, 'push');
            comp.AdvancedControllerButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AdvancedControllerButtonPushed, true);
            comp.AdvancedControllerButton.Text = 'Advanced Controller';
            comp.SkiTestingRunTestLabel = uilabel(comp);
            comp.SkiTestingRunTestLabel.Text = 'Ski Testing - Run Test';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end