classdef SetTestNameComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TestDirectoryPanel              matlab.ui.container.Panel
        NameTestPanel                   matlab.ui.container.Panel
        SimilarTestNamesthatAlreadyExistPanel  matlab.ui.container.Panel
        SimilarTestNamesDropDown        matlab.ui.control.DropDown
        SelecttestnameifitalreadyexistDropDownLabel  matlab.ui.control.Label
        OriginalTestNameSwitch          matlab.ui.control.Switch
        KeeporiginaltestnameSwitchLabel  matlab.ui.control.Label
        InputNamePanel                  matlab.ui.container.Panel
        UnderfoommDropDown              matlab.ui.control.DropDown
        UnderfootmmLabel                matlab.ui.control.Label
        SubmitButton_New                matlab.ui.control.Button
        ModelYearDropDown               matlab.ui.control.DropDown
        ModelYearDropDownLabel          matlab.ui.control.Label
        LengthcmDropDown                matlab.ui.control.DropDown
        LengthcmDropDownLabel           matlab.ui.control.Label
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        SelectExistingTestNamePanel     matlab.ui.container.Panel
        SubmitButton_Existing           matlab.ui.control.Button
        ExistingTestNamesDropDown       matlab.ui.control.DropDown
        ExistingTestNamesDropDownLabel  matlab.ui.control.Label
        HasThisSkiAlreadyBeenTestedSwitchLabel  matlab.ui.control.Label
        HasThisSkiAlreadyBeenTestedSwitch  matlab.ui.control.Switch
    end

    
    methods (Access = public)
        
        function cleaned_dirs = getExsitingTestDirNames(comp)
            data_folder_path = 'G:\.shortcut-targets-by-id\1jaXuO8culNZtbUZ1rrY8TEjG_TDjo2f5\Blister Labs Ski Flex\Ski_Data\';
            l = dir(data_folder_path);
            dirs = {l.name};
            cleaned_dirs = dirs(1,3:end);
            cleaned_dirs(ismember(cleaned_dirs,'ReadMe.txt')) = {''};
            % use contians to fins csv, png, and txt files to remove if
            % neccessary
            cleaned_dirs = cleaned_dirs(~cellfun(@isempty, cleaned_dirs));
        end

        function result = checkTestPlaceholderNameError(comp, message, placeholder, input)
            % disp("placeholder: ");
            % disp(placeholder);
            % disp("input: ");
            % disp(input);
            if input == "" || input == placeholder
               fig = uifigure;
               uialert(fig,message,'Ski Test Name Error');
               result = false;
               return;
            end
            result = true;
        end

        function result = checkIfDirNameExists(comp, dir_name)
            existing_dir_names = getExsitingTestDirNames(comp);
            if ismember(dir_name, existing_dir_names)
               str = strcat("Ski Test Name Already Exists. Please input new name or select existing name. \n Input: \n ", dir_name);
               fig = uifigure;
               uialert(fig,str,'Ski Test Name Error');
               result = false;
               return;
            end
            result = true;
        end

        function closest_test_names = check_for_similar_test_names(comp, inputed_test_name, existing_test_names)
            % Initialize dictionary d with key = string, value = double
            d = dictionary;%containers.Map('KeyType', 'char', 'ValueType', 'double');%
        
            % Loop through each existing_name in existing_test_names
            for i = 1:length(existing_test_names)
                existing_name = existing_test_names{i};
                
                % Calculate the edit distance between inputed_test_name and existing_name
                dis = editDistance(inputed_test_name, existing_name);
                
                % Store the edit distance in the dictionary
                big = max(strlength(inputed_test_name), strlength(existing_name));
                dist = dis/big;
                d(existing_name) = dist;
            end
             
            % Order the dictionary by value in descending order
            global dic;
            sorted_d = sortDictByValue(comp, d, 'ascend');
            dic = sorted_d;
            assignin('base',"sorted_d",sorted_d);
          % Get all dictionary keys that have a value greater than 0.7
            closest_test_names = {};
            keys_sorted = keys(sorted_d);
            for i = 1:length(keys_sorted)
                key = keys_sorted{i};
                value = sorted_d(key);
                if value <= 0.7
                    closest_test_names{end+1} = key;
                end
            end
            assignin('base', "closest_test_names", closest_test_names);
        end
        
        
        function sortedDict = sortDictByValue(comp, dict, sortOrder)
            % Function to sort a dictionary by value in ascending or descending order
            dictKeys = keys(dict);
            dictValues = values(dict);
            
            % Sort the dictionary values and get the corresponding indices
            [~, sortedIndices] = sort([dictValues(:)], sortOrder);
            
            % Reorder the dictionary keys based on the sorted indices
            sortedDictKeys = dictKeys(sortedIndices);
            sortedDictValues = dictValues(sortedIndices);
            
            % Create a new dictionary with the sorted keys and values
            sortedDict = dictionary(sortedDictKeys, sortedDictValues);%containers.Map(sortedDictKeys, sortedDictValues);%
        end

        function modified_str = lowercase_and_replace(comp, str)
            str = lower(str);
            str = strtrim(str);
            str = strrep(str,' ', '_');
            pattern = '[^a-zA-Z0-9_]+';
            modified_str = regexprep(str, pattern, '');
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.SelectExistingTestNamePanel.Enable = "on";
            comp.NameTestPanel.Enable = "off";
            comp.SimilarTestNamesthatAlreadyExistPanel.Enable = 'off';
            % numbers = 100:200;
            % strings = arrayfun(@num2str, numbers, 'UniformOutput', false);
            % assignin("base", "strings", strings);
            % comp.LengthcmDropDown.Items = strings;
        end

        % Value changed function: HasThisSkiAlreadyBeenTestedSwitch
        function HasThisSkiAlreadyBeenTestedSwitchValueChanged(comp, event)
            value = comp.HasThisSkiAlreadyBeenTestedSwitch.Value;
            if value == "Yes"      
                comp.SelectExistingTestNamePanel.Enable = "on";
                comp.NameTestPanel.Enable = "off";
            else
                comp.SelectExistingTestNamePanel.Enable = "off";
                comp.NameTestPanel.Enable = "on";
            end
        end

        % Drop down opening function: ExistingTestNamesDropDown
        function ExistingTestNamesDropDownOpening(comp, event)
            comp.ExistingTestNamesDropDown.Items = getExsitingTestDirNames(comp);
        end

        % Button pushed function: SubmitButton_Existing
        function SubmitButton_ExistingPushed(comp, event)
            % global test_directory_name;
            selectedSki = comp.ExistingTestNamesDropDown.Value;
            str = strcat("Ski not selected. \n Selected: \n ", selectedSki);
            res = checkTestPlaceholderNameError(comp, str, convertCharsToStrings(comp.ExistingTestNamesDropDown.Placeholder), convertCharsToStrings(selectedSki));
            if ~res; return; end;
            test_directory_name = selectedSki;   
            assignin('base','dir_name', test_directory_name);
            disp(selectedSki);
            str = strcat("Existing test directory set: ", selectedSki);
            fig = uifigure;
            uialert(fig,str,'Success Message','Icon','success');
        end

        % Button down function: NameTestPanel
        function NameTestPanelButtonDown(comp, event)
            comp.StandardNamingPanel = "off";
        end

        % Drop down opening function: LengthcmDropDown
        function LengthcmDropDownOpening(comp, event)
            %cm_arr = regexp(cellstr(sprintf('%d ',1:200)),' ','split');
            cm_arr = sprintfc('%d',100:200); % beware, undocumented!
            comp.LengthcmDropDown.Items = cm_arr;
        end

        % Button pushed function: SubmitButton_New
        function SubmitButton_NewPushed(comp, event)
            %global test_directory_name
            manufacturer = lowercase_and_replace(comp, convertCharsToStrings(comp.ManufacturerEditField.Value));
            model = lowercase_and_replace(comp, convertCharsToStrings(comp.ModelNameEditField.Value));
            year = convertCharsToStrings(comp.ModelYearDropDown.Value);
            length = convertCharsToStrings(comp.LengthcmDropDown.Value);
            underfoot = convertCharsToStrings(comp.UnderfoommDropDown.Value);
            res = checkTestPlaceholderNameError(comp, "manufacturer name not inputted", convertCharsToStrings(comp.ManufacturerEditField.Placeholder), manufacturer);
            if ~res; return; end;
            res = checkTestPlaceholderNameError(comp, "model name not inputted", convertCharsToStrings(comp.ModelNameEditField.Placeholder), model);
            if ~res; return; end;
            % res = checkTestPlaceholderNameError(comp, "model year was not inputted", convertCharsToStrings(comp.ModelYearEditField.Placeholder), year);
            % if ~res; return; end;
            % res = checkTestPlaceholderNameError(comp, "model length was not inputted", convertCharsToStrings(comp.LengthcmDropDown.Placeholder), length);
            % if ~res; return; end;
            dir_name = strcat(manufacturer,"_",model,"_",year,"_",length,"cm_",underfoot,"mm");
            res = checkIfDirNameExists(comp, dir_name);
            if ~res; return; end;
            test_directory_name = dir_name;
            assignin('base', 'temp_dir_name', test_directory_name);

            existing_dirs = getExsitingTestDirNames(comp);
            % disp("Similar Names");
            % disp(existing_dirs');
            % disp("Type of Array");
            % disp(class(existing_dirs'));
            assignin('base','existing_names', existing_dirs');
            closest_dirs = check_for_similar_test_names(comp, test_directory_name, existing_dirs');
            
            if ~isempty(closest_dirs)
                assignin('base','similarTestNames',closest_dirs);
                assignin('base','input_name', test_directory_name);
                comp.SimilarTestNamesthatAlreadyExistPanel.Enable="on";
                % system('matlab -r "SimilarTestNamesComp"');
                % waitfor(SimilarTestNamesComp,'BeingDeleted');
            else
                assignin('base','dir_name',test_directory_name);
                str = strcat("New test directory has been made: ", evalin('base', 'dir_name'));
                fig = uifigure;
                uialert(fig,str,'Success Message','Icon','success');
            end
        end

        % Drop down opening function: ModelYearDropDown
        function ModelYearDropDownOpening(comp, event)
            year_arr = sprintfc('%d',1990:2030); % beware, undocumented!
            comp.ModelYearDropDown.Items = year_arr;
        end

        % Drop down opening function: SimilarTestNamesDropDown
        function SimilarTestNamesDropDownOpening(comp, event)
            test_names = evalin('base','similarTestNames'); 
            disp(test_names);
            test_names(end+1) = {'Options'};
            assignin('base','dropdown_list',test_names);
            comp.SimilarTestNamesDropDown.Items = test_names;
        end

        % Value changed function: OriginalTestNameSwitch
        function OriginalTestNameSwitchValueChanged(comp, event)
            value = comp.OriginalTestNameSwitch.Value;
            if value == "Yes"
                inputted_name = evalin('base', 'input_name');
                assignin('base', 'dir_name', inputted_name);
                str = strcat("New test directory has been made: ", evalin('base', 'dir_name'));
                fig = uifigure;
                uialert(fig,str,'Success Message','Icon','success');
            end
            
        end

        % Value changed function: SimilarTestNamesDropDown
        function SimilarTestNamesDropDownValueChanged(comp, event)
            value = comp.SimilarTestNamesDropDown.Value;
            assignin('base', 'dir_name', value); 
            str = strcat("New test directory has been made: ", evalin('base', 'dir_name'));
            fig = uifigure;
            uialert(fig,str,'Success Message','Icon','success');
        end

        % Drop down opening function: UnderfoommDropDown
        function UnderfoommDropDownOpening(comp, event)
            cm_arr = sprintfc('%d',60:130); % beware, undocumented!
            comp.UnderfoommDropDown.Items = cm_arr;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 486 654];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TestDirectoryPanel = uipanel(comp);
            comp.TestDirectoryPanel.Title = 'Test Directory ';
            comp.HasThisSkiAlreadyBeenTestedSwitch = uiswitch(comp.TestDirectoryPanel, 'slider');
            comp.HasThisSkiAlreadyBeenTestedSwitch.Items = {'Yes', 'No'};
            comp.HasThisSkiAlreadyBeenTestedSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @HasThisSkiAlreadyBeenTestedSwitchValueChanged, true);
            comp.HasThisSkiAlreadyBeenTestedSwitch.Value = 'Yes';
            comp.HasThisSkiAlreadyBeenTestedSwitchLabel = uilabel(comp.TestDirectoryPanel);
            comp.HasThisSkiAlreadyBeenTestedSwitchLabel.Text = 'Has This Ski Already Been Tested';
            comp.SelectExistingTestNamePanel = uipanel(comp.TestDirectoryPanel);
            comp.SelectExistingTestNamePanel.Title = 'Select Existing Test Name';
            comp.ExistingTestNamesDropDownLabel = uilabel(comp.SelectExistingTestNamePanel);
            comp.ExistingTestNamesDropDownLabel.Text = 'Existing Test Names';
            comp.ExistingTestNamesDropDown = uidropdown(comp.SelectExistingTestNamePanel);
            comp.ExistingTestNamesDropDown.Items = {'Options'};
            comp.ExistingTestNamesDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @ExistingTestNamesDropDownOpening, true);
            comp.ExistingTestNamesDropDown.Placeholder = 'Options';
            comp.ExistingTestNamesDropDown.Value = 'Options';
            comp.SubmitButton_Existing = uibutton(comp.SelectExistingTestNamePanel, 'push');
            comp.SubmitButton_Existing.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SubmitButton_ExistingPushed, true);
            comp.SubmitButton_Existing.Text = 'Submit';
            comp.NameTestPanel = uipanel(comp.TestDirectoryPanel);
            comp.NameTestPanel.Title = 'Name Test';
            comp.NameTestPanel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @NameTestPanelButtonDown, true);
            comp.InputNamePanel = uipanel(comp.NameTestPanel);
            comp.InputNamePanel.Title = 'Input Name';
            comp.ManufacturerEditFieldLabel = uilabel(comp.InputNamePanel);
            comp.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            comp.ManufacturerEditField = uieditfield(comp.InputNamePanel, 'text');
            comp.ManufacturerEditField.Placeholder = 'Enter Manufacturer';
            comp.ModelNameEditFieldLabel = uilabel(comp.InputNamePanel);
            comp.ModelNameEditFieldLabel.Text = 'Model Name';
            comp.ModelNameEditField = uieditfield(comp.InputNamePanel, 'text');
            comp.ModelNameEditField.Placeholder = 'Enter Model Name';
            comp.LengthcmDropDownLabel = uilabel(comp.InputNamePanel);
            comp.LengthcmDropDownLabel.Text = 'Length (cm)';
            comp.LengthcmDropDown = uidropdown(comp.InputNamePanel);
            comp.LengthcmDropDown.Items = {'Options'};
            comp.LengthcmDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @LengthcmDropDownOpening, true);
            comp.LengthcmDropDown.Value = 'Options';
            comp.ModelYearDropDownLabel = uilabel(comp.InputNamePanel);
            comp.ModelYearDropDownLabel.Text = 'Model Year';
            comp.ModelYearDropDown = uidropdown(comp.InputNamePanel);
            comp.ModelYearDropDown.Items = {'Options'};
            comp.ModelYearDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @ModelYearDropDownOpening, true);
            comp.ModelYearDropDown.Value = 'Options';
            comp.SubmitButton_New = uibutton(comp.InputNamePanel, 'push');
            comp.SubmitButton_New.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SubmitButton_NewPushed, true);
            comp.SubmitButton_New.Text = 'Submit';
            comp.UnderfootmmLabel = uilabel(comp.InputNamePanel);
            comp.UnderfootmmLabel.Text = 'Underfoot (mm)';
            comp.UnderfoommDropDown = uidropdown(comp.InputNamePanel);
            comp.UnderfoommDropDown.Items = {'Options'};
            comp.UnderfoommDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @UnderfoommDropDownOpening, true);
            comp.UnderfoommDropDown.Value = 'Options';
            comp.SimilarTestNamesthatAlreadyExistPanel = uipanel(comp.NameTestPanel);
            comp.SimilarTestNamesthatAlreadyExistPanel.Title = 'Similar Test Names that Already Exist';
            comp.KeeporiginaltestnameSwitchLabel = uilabel(comp.SimilarTestNamesthatAlreadyExistPanel);
            comp.KeeporiginaltestnameSwitchLabel.Text = 'Keep original test name?';
            comp.OriginalTestNameSwitch = uiswitch(comp.SimilarTestNamesthatAlreadyExistPanel, 'slider');
            comp.OriginalTestNameSwitch.Items = {'Yes', 'No'};
            comp.OriginalTestNameSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @OriginalTestNameSwitchValueChanged, true);
            comp.OriginalTestNameSwitch.Value = 'No';
            comp.SelecttestnameifitalreadyexistDropDownLabel = uilabel(comp.SimilarTestNamesthatAlreadyExistPanel);
            comp.SelecttestnameifitalreadyexistDropDownLabel.Text = 'Select test name if it already exist';
            comp.SimilarTestNamesDropDown = uidropdown(comp.SimilarTestNamesthatAlreadyExistPanel);
            comp.SimilarTestNamesDropDown.Items = {'Options'};
            comp.SimilarTestNamesDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @SimilarTestNamesDropDownOpening, true);
            comp.SimilarTestNamesDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SimilarTestNamesDropDownValueChanged, true);
            comp.SimilarTestNamesDropDown.Value = 'Options';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end