classdef SimilarTestNamesComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        DoneButton                matlab.ui.control.Button
        OriginalTestNameSwitch    matlab.ui.control.Switch
        KeeporiginaltestnameSwitchLabel  matlab.ui.control.Label
        SimilarTestNamesDropDown  matlab.ui.control.DropDown
        SelecttestnameifitalreadyexistDropDownLabel  matlab.ui.control.Label
        SimilarTestNamesthatAlreadyExistLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            test_names = evalin('base','similarTestNames'); 
            comp.SimilarTestNamesDropDownOpening.Items = test_names;
        end

        % Drop down opening function: SimilarTestNamesDropDown
        function SimilarTestNamesDropDownOpening(comp, event)
            test_names = evalin('base','similarTestNames');
            comp.SimilarTestNamesDropDownOpening.Items = test_names;
        end

        % Value changed function: SimilarTestNamesDropDown
        function SimilarTestNamesDropDownValueChanged(comp, event)
            value = comp.SimilarTestNamesDropDown.Value; 
            assignin('base', 'dir_name', value); 
        end

        % Value changed function: OriginalTestNameSwitch
        function OriginalTestNameSwitchValueChanged(comp, event)
            value = comp.OriginalTestNameSwitch.Value;
            if value == "Yes"
                inputted_name = evalin('base', 'input_name');
                assignin('base', 'dir_name', inputted_name);
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(comp, event)
            closereq();
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 339 223];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.SimilarTestNamesthatAlreadyExistLabel = uilabel(comp);
            comp.SimilarTestNamesthatAlreadyExistLabel.Text = 'Similar Test Names that Already Exist';
            comp.SelecttestnameifitalreadyexistDropDownLabel = uilabel(comp);
            comp.SelecttestnameifitalreadyexistDropDownLabel.Text = 'Select test name if it already exist';
            comp.SimilarTestNamesDropDown = uidropdown(comp);
            comp.SimilarTestNamesDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @SimilarTestNamesDropDownOpening, true);
            comp.SimilarTestNamesDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SimilarTestNamesDropDownValueChanged, true);
            comp.KeeporiginaltestnameSwitchLabel = uilabel(comp);
            comp.KeeporiginaltestnameSwitchLabel.Text = 'Keep original test name?';
            comp.OriginalTestNameSwitch = uiswitch(comp, 'slider');
            comp.OriginalTestNameSwitch.Items = {'Yes', 'No'};
            comp.OriginalTestNameSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @OriginalTestNameSwitchValueChanged, true);
            comp.OriginalTestNameSwitch.Value = 'No';
            comp.DoneButton = uibutton(comp, 'push');
            comp.DoneButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DoneButtonPushed, true);
            comp.DoneButton.Text = 'Done';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end