classdef SimpleSetComs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SetCOMsPanel             matlab.ui.container.Panel
        ConnecttoCOMPortsButton  matlab.ui.control.Button
        ClearCOMPortsButton      matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = setSerial(comp, serial_name, port_name, port, baud)
            try
               assignin('base',port_name, []);
               assignin('base',port_name,port);
               assignin('base',serial_name, []);
               serial = serialport(port, baud);               
               assignin('base',serial_name, serial);
               %disp("serail set");
            catch e
               m = getReport(e,"extended");
               str = strcat("failed to connect serial. \n Exception: \n ", m);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function DropDownArduinoOpening(comp, event)
            % set all drop down options to avalible com ports
            freeports = serialportlist("available");
            comp.DropDownArduino.Items = freeports;
        end

        % Callback function: not associated with a component
        function SetArduinoCOMButtonPushed(comp, event)
            %global arduino_serail;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownArduino.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownArduino.Placeholder)
                %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
               return;
            end
            % TestingUI.arduino_port = selectedCOM;
            %disp("option not selected, comport coing to be set");
            setSerial(comp, 'arduino_serial', 'arduino_port', selectedCOM, 115200);
            % comp.DropDownArduino.Items = {selectedCOM};
        end

        % Callback function: not associated with a component
        function SetSkiInclinometerCOMButtonPushed(comp, event)
            %global inclinometer_front_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownSkiInclinometer.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownSkiInclinometer.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.inclinometer_port_front = selectedCOM;
            setSerial(comp, "ski_inclinometer_serial", "ski_inlinometer_port", selectedCOM, 9600);
            % comp.DropDownSkiInclinometer.Items = {selectedCOM};
        end

        % Callback function: not associated with a component
        function SetLevelInclinomterCOMButtonPushed(comp, event)
            %global inclinometer_back_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownLevelInclinometer.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownLevelInclinometer.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.inclinometer_port_back = selectedCOM;
            setSerial(comp, "level_inclinometer_serial", "level_inlcinometer_port", selectedCOM, 9600);
            % comp.DropDownLevelInclinometer.Items = {selectedCOM};
        end

        % Callback function: not associated with a component
        function SetForceGaugeLeftCOMButtonPushed(comp, event)
            %global force_gage_left_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceLeft.Value;
            disp(class(selectedCOM));
            disp(class(comp.DropDownForceLeft.Placeholder));
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownForceLeft.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.force_gage1_port = selectedCOM;
            setSerial(comp, "force_gauge_left_serial","force_gauge_left_port", selectedCOM, 9600);
            % comp.DropDownForceLeft.Items = {selectedCOM};
        end

        % Callback function: not associated with a component
        function SetForceGaugeRightCOMButtonPushed(comp, event)
            %global force_gage_right_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceRight.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownForceRight.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.force_gauge2_port = selectedCOM;
            setSerial(comp, "force_gauge_right_serial","force_gauge_right_port", selectedCOM, 9600);
            % comp.DropDownForceRight.Items = {selectedCOM};
        end

        % Button pushed function: ClearCOMPortsButton
        function ClearCOMPortsButtonPushed(comp, event)
            evalin('base', 'clear arduino_serial ski_inclinometer_serial level_inclinometer_serial force_gauge_left_serial force_gauge_right_serial');
           
        end

        % Callback function: not associated with a component
        function DropDownSkiInclinometerOpening(comp, event)
            freeports = serialportlist("available");
            comp.DropDownSkiInclinometer.Items = freeports;
        end

        % Callback function: not associated with a component
        function DropDownLevelInclinometerOpening(comp, event)
            freeports = serialportlist("available");
            comp.DropDownLevelInclinometer.Items = freeports;
        end

        % Callback function: not associated with a component
        function DropDownForceLeftOpening(comp, event)
            freeports = serialportlist("available");
            comp.DropDownForceLeft.Items = freeports;
        end

        % Callback function: not associated with a component
        function DropDownForceRightOpening(comp, event)
            freeports = serialportlist("available");
            comp.DropDownForceRight.Items = freeports;
        end

        % Button pushed function: ConnecttoCOMPortsButton
        function ConnecttoCOMPortsButtonPushed(comp, event)
            try
                setSerial(comp, "arduino_serial", "arduino_port", "COM16", 115200);
                setSerial(comp, "ski_inclinometer_serial", "ski_inclinometer_port", 'COM12', 9600);
                setSerial(comp, "level_inclinometer_serial", "level_inlcinometer_port", 'COM11', 9600);
                setSerial(comp, "force_gauge_left_serial","force_gauge_left_port", 'COM7', 9600);
                setSerial(comp, "force_gauge_right_serial","force_gauge_right_port", 'COM8', 9600);
            catch e
               m = getReport(e,"extended");
               str = strcat("failed to connect serial. \n Exception: \n ", m);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            freeports = serialportlist("available");
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 331 247];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.SetCOMsPanel = uipanel(comp);
            comp.SetCOMsPanel.Title = 'Set COMs';
            comp.ClearCOMPortsButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.ClearCOMPortsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ClearCOMPortsButtonPushed, true);
            comp.ClearCOMPortsButton.Text = 'Clear COM Ports';
            comp.ConnecttoCOMPortsButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.ConnecttoCOMPortsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ConnecttoCOMPortsButtonPushed, true);
            comp.ConnecttoCOMPortsButton.Text = 'Connect to COM Ports';
        end
    end
end