classdef TestingControllerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TestingPanel                  matlab.ui.container.Panel
        EditField                     matlab.ui.control.NumericEditField
        SetNumberofTestsButton        matlab.ui.control.Button
        clearDataButton               matlab.ui.control.Button
        KillSwitchPressedGo           matlab.ui.control.Button
        KillSwitchPressedDropDown     matlab.ui.control.DropDown
        KillswitchpressedSelecthowtoproceedDropDownLabel  matlab.ui.control.Label
        TestNameLabel                 matlab.ui.control.Label
        SaveTestButton                matlab.ui.control.Button
        RunSelectedTestButton         matlab.ui.control.Button
        SelectTesttoRunDropDown       matlab.ui.control.DropDown
        SelectTesttoRunDropDownLabel  matlab.ui.control.Label
        GJTestAxis                    matlab.ui.control.UIAxes
        EITestAxis                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% AUTOMATION FUCNTIONS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
% SENSOR_AUTOMATION Collects sensor data during automated ski flex tests.
%
%   return_matrix = SENSOR_AUTOMATION(comp, test_interval_mm, direction) is a function that automates the ski flex testing process.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       test_interval_mm: The distance (in millimeters) to move the inclinometer after each data collection.
%       direction: The direction of movement for the inclinometer. Use 'forward' or 'backward'.
%
%   OUTPUT:
%       return_matrix: A matrix containing sensor data for each test interval. The matrix has dimensions Mx4, where M is the number of collected data points.
%
%   This function collects data from an inclinometer and force sensors while moving the inclinometer in a specified direction for a given test interval. The function relies on pre-established serial connections to the sensors stored in the MATLAB workspace.
%   The data collected during the ski flex tests are saved in a matrix named 'data_matrix'. The function continues collecting data until one of the following conditions is met:
%       - Limit switch is hit (stop_num == 42),
%       - Kill switch is hit (stop_num == 86 and/or kill_switch_pressed == true),
%       - The specified test interval is completed.
%
%   The sensor data collected during the tests are organized into rows, with each row representing a single data point. The four columns of the 'data_matrix' contain the following data:
%       1. Pitch (degrees)
%       2. Roll (degrees)
%       3. Force from left force gauge (units may vary based on calibration)
%       4. Force from right force gauge (units may vary based on calibration)
%
%   Before starting the test, the inclinometer is flushed to discard any buffered data. The initial pitch and roll readings are saved to the MATLAB workspace.
%   During the test, the function waits for inclinometer and force data at each test interval and saves the collected data into the 'data_matrix'.
%
%   The function also checks for the kill switch status. If the kill switch is pressed during the test, the data from the current test is cleared from the MATLAB workspace.
%
%   NOTE: Ensure that the required serial connections to the sensors are established and available in the MATLAB workspace before calling this function. The function 'get_inclinometer_reading', 'get_HWT905TTL_data', 'force_average', and 'move_x_mm' are used to retrieve sensor data and control the movement of the inclinometer.

        function return_matrix = sensor_automation(comp, test_interval_mm, direction) %inclinometer_serial
            
                ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                arduino_serial  = evalin("base", "arduino_serial");
                force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
                data_matrix = zeros(0, 4);
                stop_num=0;
                flush(ski_inclinometer_serial);
                [pitch, roll] = get_HWT905TTL_data(comp,ski_inclinometer_serial); % for flushing the inclinometer
                assignin("base", "current_pitch", pitch);
                assignin("base", "current_roll", roll);
                kill_switch_pressed = evalin("base", "kill_switch_pressed");

                while stop_num~=42 && stop_num ~= 86 && kill_switch_pressed ~= 1
                    %collect data
                    pause(.125); 
                    flush(ski_inclinometer_serial);
                    disp("waiting for incl data");
                    [pitch, roll] = get_inclinometer_reading(comp,ski_inclinometer_serial);
                    disp("waiting for force data");
                    [force1, force2]=force_average(comp,force_gauge_left_serial, force_gauge_right_serial, 1);
            
                    row_entry = [pitch, roll, force1, force2];               
                    data_matrix = [data_matrix;row_entry];
                    disp("data_matrix: ");
                    disp(data_matrix);
                                    
                    %MOVE SENSOR
                    disp("waiting for move");
                    sig = move_x_mm(comp, -1*test_interval_mm, direction, arduino_serial);
                    disp(sig);
                    stop_num = str2double(sig);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
                if kill_switch_pressed
                    current_test = evalin('base', 'current_test');
                    evalin('base', strcat("clear ", current_test));
                end
                return_matrix = data_matrix;
        end

% MOVE_X_MM Moves the inclinometer a specified distance in millimeters in the given direction.
%
%   ret_signal = MOVE_X_MM(comp, dis_mm, dir, s) is a function that controls the movement of the inclinometer.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       dis_mm: The distance (in millimeters) to move the inclinometer.
%       dir: The direction of movement for the inclinometer. Use 'forward' or 'backward'.
%       s: The serial port object for communication with the Arduino controlling the inclinometer movement.
%
%   OUTPUT:
%       ret_signal: A signal returned from the serial communication indicating the status of the movement.
%
%   This function moves the inclinometer a specified distance in the given direction (forward or backward) using the 'MOVE_X' command (signal 4) over the specified serial connection 's'.
%   Before initiating the movement, the function checks the status of the kill switch ('kill_switch_pressed'). If the kill switch is pressed (kill_switch_pressed == true), the function returns without performing the movement and returns a kill switch signal (86).
%
%   The function then sends a serial string to the Arduino in the format "MOVE_X,dis_mm,dir" to initiate the movement. The 'ret_signal' received from the Arduino through the serial communication indicates the status of the movement.
%   If the 'ret_signal' is equal to the 'KILL_SWITCH_SIGNAL' (86), it means the kill switch was pressed during the movement. In such a case, the 'kill_switch_pressed' flag is set to true, and the kill switch UI elements in the App Designer (e.g., DropDown and Go Button) are enabled and made visible.
%
%   After completing the movement, the function flushes the serial port to discard any buffered data.

        function ret_signal = move_x_mm(comp, dis_mm, dir, s)
            MOVE_X = 4;
            KILL_SWITCH_SIGNAL = 86;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if ~kill_switch_pressed
                serial_string = strcat(num2str(MOVE_X),",",num2str(dis_mm),",",num2str(dir));
                ret_signal = serial_communication(comp, s, serial_string);
                if str2num(ret_signal) == KILL_SWITCH_SIGNAL
                    assignin("base","kill_switch_pressed",true); 
                    comp.KillSwitchPressedDropDown.Enable = "on";
                    comp.KillSwitchPressedDropDown.Visible = "on";
                    comp.KillSwitchPressedGo.Enable = "on";
                    comp.KillSwitchPressedGo.Visible = "on";
                end
                flush(s);
            else
                ret_signal = 86;
            end
        end

% RETURN_TO_START Moves the inclinometer to its initial position (start point).
%
%   ret_signal = RETURN_TO_START(comp, s) is a function that moves the inclinometer to its initial position.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       s: The serial port object for communication with the Arduino controlling the inclinometer movement.
%
%   OUTPUT:
%       ret_signal: A signal returned from the serial communication indicating the status of the movement.
%
%   This function moves the inclinometer back to its initial position (start point) using the 'MOVE_TO_START' command (signal 2) over the specified serial connection 's'.
%   Before initiating the movement, the function checks the status of the kill switch ('kill_switch_pressed'). If the kill switch is pressed (kill_switch_pressed == true), the function returns without performing the movement and returns a kill switch signal (86).
%
%   The function then sends a serial string to the Arduino in the format "MOVE_TO_START,0,1" to initiate the movement. The 'ret_signal' received from the Arduino through the serial communication indicates the status of the movement.
%   If the 'ret_signal' is equal to the 'KILL_SWITCH_SIGNAL' (86), it means the kill switch was pressed during the movement. In such a case, the 'kill_switch_pressed' flag is set to true, and the kill switch UI elements in the App Designer (e.g., DropDown and Go Button) are enabled and made visible.
%
%   After completing the movement, the function flushes the serial port to discard any buffered data.

        function ret_signal = return_to_start(comp, s)
            MOVE_TO_START = 2;
            KILL_SWITCH_SIGNAL = 86;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if ~kill_switch_pressed
                serial_string = strcat(num2str(MOVE_TO_START),",0,1");
                ret_signal = serial_communication(comp, s, serial_string);
                if str2num(ret_signal) == KILL_SWITCH_SIGNAL
                    assignin("base","kill_switch_pressed",true); 
                    comp.KillSwitchPressedDropDown.Enable = "on";
                    comp.KillSwitchPressedDropDown.Visible = "on";
                    comp.KillSwitchPressedGo.Enable = "on";
                    comp.KillSwitchPressedGo.Visible = "on";
                end
                flush(s);
            else
                ret_signal = 86;
            end
        end 

% SET_CURRENT_POSITION Sets the inclinometer to a specified position.
%
%   ret_signal = SET_CURRENT_POSITION(comp, pos) is a function that sets the inclinometer to a specified position.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       pos: The desired position for the inclinometer.
%
%   OUTPUT:
%       ret_signal: A signal returned from the Arduino through serial communication indicating the status of the command execution.
%
%   This function sets the inclinometer to a specified position using the 'SET_CURRENT_POS' command (signal 6) over the Arduino serial connection 'arduino_serial'.
%   The desired position 'pos' is sent to the Arduino in the format "SET_CURRENT_POS,pos,0" through the serial communication.
%   
%   Before calling this function, ensure that the 'arduino_serial' object is available in the MATLAB workspace.
%   The 'custom_write' function is used to send the serial string to the Arduino, and the 'custom_read' function is used to receive the response signal.
%   The response 'ret_signal' received from the Arduino indicates the status of the command execution.
%
%   After executing the command, the function flushes the serial port to discard any buffered data.

        function ret_signal = set_current_position(comp, pos)
            SET_CURRENT_POS = 6;
            arduino_serial = evalin('base', 'arduino_serial');
            serial_string = strcat(num2str(SET_CURRENT_POS),",",num2str(pos),",0");
            %ret_signal = serial_communication(s, serial_string);
            custom_write(comp, arduino_serial, serial_string);
            ret_signal = custom_read(comp, arduino_serial);
            flush(arduino_serial);
        end

% MOVE_FORCE_GAUGES Moves the force gauges to specified positions.
%
%   ret_signal = MOVE_FORCE_GAUGES(comp, s, left_mm, right_mm) is a function that controls the movement of the force gauges.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       s: The serial port object for communication with the Arduino controlling the force gauges.
%       left_mm: The distance (in millimeters) to move the left force gauge.
%       right_mm: The distance (in millimeters) to move the right force gauge.
%
%   OUTPUT:
%       ret_signal: A signal returned from the serial communication indicating the status of the movement.
%
%   This function moves the left and right force gauges to specified positions using the 'MOVE_FORCE_GAUGES' command (signal 24) over the specified serial connection 's'.
%   The distances 'left_mm' and 'right_mm' are sent to the Arduino in the format "MOVE_FORCE_GAUGES,left_mm,right_mm" through the serial communication.
%
%   Before initiating the movement, the function checks the status of the kill switch ('kill_switch_pressed'). If the kill switch is pressed (kill_switch_pressed == true), the function returns without performing the movement and returns a kill switch signal (86).
%
%   The function then sends the serial string to the Arduino, and the 'ret_signal' received from the Arduino through the serial communication indicates the status of the movement.
%   If the 'ret_signal' is equal to the 'KILL_SWITCH_SIGNAL' (86), it means the kill switch was pressed during the movement. In such a case, the 'kill_switch_pressed' flag is set to true, and the kill switch UI elements in the App Designer (e.g., DropDown and Go Button) are enabled and made visible.
%
%   After completing the movement, the function flushes the serial port to discard any buffered data.

        function ret_signal = move_force_gauges(comp, s, left_mm, right_mm)
            MOVE_FORCE_GAUGES = 24;
            KILL_SWITCH_SIGNAL = 86;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if ~kill_switch_pressed
                serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(left_mm),",",num2str(right_mm));
                ret_signal = serial_communication(comp, s, serial_string);
                if str2num(ret_signal) == KILL_SWITCH_SIGNAL
                    assignin("base","kill_switch_pressed",true); 
                    comp.KillSwitchPressedDropDown.Enable = "on";
                    comp.KillSwitchPressedDropDown.Visible = "on";
                    comp.KillSwitchPressedGo.Enable = "on";
                    comp.KillSwitchPressedGo.Visible = "on";
                end
                flush(s);
            else
                ret_signal = 86;
            end

        end

% MOVE_FORCE_GAUGES_UNTIL_DESIRED_FORCE Moves the force gauges until reaching the desired force level.
%
%   ret_signal = MOVE_FORCE_GAUGES_UNTIL_DESIRED_FORCE(comp, s, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size, direction, precision) is a function that controls the movement of the force gauges until reaching the specified desired force level.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       s: The serial port object for communication with the Arduino controlling the force gauges.
%       force_gauge_left_serial: The serial port object for the left force gauge.
%       force_gauge_right_serial: The serial port object for the right force gauge.
%       desired_force: The desired force level (in units of the force gauges) to reach.
%       step_size: The step size (in units of the force gauges) to increment or decrement during movement.
%       direction: The direction of movement for the force gauges. Use 'up' to increase force or 'down' to decrease force.
%       precision: The precision level (in units of the force gauges) to define the target force range. The function stops movement when the measured force is within (desired_force ± precision).
%
%   OUTPUT:
%       ret_signal: A signal returned from the serial communication indicating the status of the movement.
%
%   This function moves the left and right force gauges incrementally until reaching the specified desired force level using the 'MOVE_FORCE_GAUGES' command (signal 24) over the specified serial connection 's'.
%   The desired force level is determined by 'desired_force' and the desired precision around it is defined by 'precision'. The direction of movement is specified by the 'direction' input.
%   The 'step_size' determines the size of the incremental steps taken during movement.
%
%   Before initiating the movement, the function measures the current force values from the left and right force gauges using the 'force_average' function.
%   The function continues moving the force gauges until both the left and right forces are within the desired range (desired_force ± precision), or the kill switch is pressed, or the 'ret_signal' indicates a kill switch signal (86).
%
%   During movement, the function uses 'serial_communication' to send the serial string to the Arduino and receives the response 'ret_signal'.
%
%   After completing the movement, the function flushes the serial port to discard any buffered data.
        
        function ret_signal = move_force_gauges_until_desired_force(comp, s, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size, direction, precision)
            MOVE_FORCE_GAUGES = 24;
            KILL_SWITCH_SIGNAL = 86;
            [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            ret_signal = 24;
            if direction == "up"
                while (force_left < (desired_force-precision) || force_right < (desired_force-precision)) && ~(str2double(ret_signal) == 86) && kill_switch_pressed ~= 86
                    serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(step_size),",",num2str(step_size));
                    ret_signal = serial_communication(comp, s, serial_string);
                    [force_left, force_right] = force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
            elseif direction == "down" 
                disp("attempting to go down");
                if force_right < 20 || force_left < 20
                    step_size = 10;
                end
                if force_right < 10 || force_left < 10
                    step_size = 5;
                end
                if force_right < 5 || force_left < 5
                    step_size = 2;
                end
                while (force_left > (desired_force+precision) || force_right > (desired_force+precision)) && ~(str2double(ret_signal) == 86) && kill_switch_pressed ~= 86
                    serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(-1*step_size),",",num2str(-1*step_size));
                    ret_signal = serial_communication(comp, s, serial_string);
                    disp(ret_signal);
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);

                    if force_right < 20 || force_left < 20
                        step_size = 10;
                    end
                    if force_right < 10 || force_left < 10
                        step_size = 5;
                    end
                    if force_right < 5 || force_left < 5
                        step_size = 2;
                    end
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
            end
            
            flush(s);
        end
        
% LEVEL_FORCE_GAUGES Levels the force gauges to achieve the desired angle.
%
%   ret_signal = LEVEL_FORCE_GAUGES(comp, s, desired_angle, precision, inclinometer, step_size) is a function that controls the movement of the force gauges to level them and achieve the desired angle.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       s: The serial port object for communication with the Arduino controlling the force gauges.
%       desired_angle: The desired angle (in degrees) to level the force gauges.
%       precision: The precision level (in degrees) to define the target angle range. The function stops movement when the measured angle is within (desired_angle ± precision).
%       inclinometer: The serial port object for the inclinometer.
%       step_size: The step size (in degrees) to incrementally adjust the force gauges during movement.
%
%   OUTPUT:
%       ret_signal: A signal returned from the serial communication indicating the status of the movement.
%
%   This function moves the force gauges incrementally to level them and achieve the desired angle using the 'MOVE_FORCE_GAUGES' command (signal 24) over the specified serial connection 's'.
%   The desired angle is specified by 'desired_angle', and the desired precision around it is defined by 'precision'. The inclinometer readings are used to determine the current pitch angle.
%   The function uses the 'get_HWT905TTL_data' and 'get_inclinometer_reading' functions to get the current pitch angle from the inclinometer and adjusts it based on the specified angle conventions.
%
%   The function continues moving the force gauges until the measured pitch angle is within the desired range (desired_angle ± precision), or the kill switch is pressed, or the 'ret_signal' indicates a kill switch signal (86).
%   During movement, the function uses 'serial_communication' to send the serial string to the Arduino and receives the response 'ret_signal'.
%
%   The 'step_size' is adjusted adaptively based on the difference between the current pitch angle and the desired angle. It helps optimize the convergence speed and precision of the leveling process.
%
%   After completing the movement, the function flushes the serial port to discard any buffered data.

        function ret_signal = level_force_gauges(comp, s, desired_angle, precision, inclinometer, step_size)
            MOVE_FORCE_GAUGES = 24;
            KILL_SWITCH_SIGNAL = 86;
            [pitch, roll] = get_HWT905TTL_data(comp, inclinometer);
            pitch = pitch *-1;
            assignin("base", "current_pitch", pitch);
            assignin("base", "current_roll", roll);
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            %desired_angle = desired_angle + 90;

            % disp(strcat("pitch: ", num2str(pitch)));
            % disp(strcat("roll: ", num2str(roll)));
            ret_signal = 24;
            if abs(pitch - desired_angle) < 10
                step_size = 7;
            end
            if abs(pitch - desired_angle) < 5
                step_size = 5;
            end
            if abs(pitch - desired_angle) < 1
                step_size = 1;
            end
            while ~(pitch < (desired_angle + precision) && pitch > (desired_angle - precision)) && ~(str2double(ret_signal) == 86) && kill_switch_pressed ~= 86;
                % step_size = gradient descent step size adjustment(roll, desired_angle, step_size)
                message = make_message(comp, int2str(MOVE_FORCE_GAUGES), pitch, desired_angle, precision, step_size);
                ret_signal = serial_communication(comp, s, message);
                [pitch, roll] = get_inclinometer_reading(comp, inclinometer);
                pitch = pitch *-1;
                disp(strcat("pitch: ", num2str(pitch)));
                disp(strcat("roll: ", num2str(roll)));
                if abs(pitch - desired_angle) < 10
                    step_size = 7;
                end
                if abs(roll - desired_angle) < 5
                    step_size = 5;
                end
                if abs(pitch - desired_angle) < 1
                    step_size = 1;
                end
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
            end
            flush(s);
        end

% MAKE_MESSAGE Generates a serial message for controlling force gauges based on the actual and desired angles.
%
%   message = MAKE_MESSAGE(comp, m, actual_angle, desired_angle, precision, step_size) is a function that generates a serial message to control the force gauges based on the actual and desired angles.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       m: The movement command number (signal) to be included in the message.
%       actual_angle: The current actual angle (in degrees) measured by the inclinometer.
%       desired_angle: The desired angle (in degrees) to level the force gauges.
%       precision: The precision level (in degrees) to define the target angle range.
%       step_size: The step size (in degrees) to incrementally adjust the force gauges during movement.
%
%   OUTPUT:
%       message: A serial message to control the force gauges in the format "m,left_step_size,right_step_size".
%
%   This function generates a serial message 'message' for controlling the force gauges based on the actual and desired angles. The message includes the movement command number (signal) 'm'.
%
%   The function compares the actual angle 'actual_angle' measured by the inclinometer with the desired angle 'desired_angle'. If the actual angle is within the desired range (desired_angle ± precision), the force gauges do not need to be adjusted (both left and right step sizes are set to 0).
%   If the actual angle is less than the desired angle, the function sets the left step size to 0 (no adjustment required for left force gauge) and the right step size to 'step_size' to move the right force gauge incrementally in the positive direction.
%   If the actual angle is greater than the desired angle, the function sets the right step size to 0 (no adjustment required for right force gauge) and the left step size to 'step_size' to move the left force gauge incrementally in the positive direction.
%
%   The generated message is returned as a comma-separated string in the format "m,left_step_size,right_step_size", where 'm' is the movement command number and 'left_step_size' and 'right_step_size' are the step sizes for the left and right force gauges, respectively.

        function message = make_message(comp, m, actual_angle, desired_angle, precision, step_size)
            message = m;
            if actual_angle <= desired_angle+precision && actual_angle >= desired_angle-precision % make this a fuction that takes in actual and desired, concatenates and retruns message
                  message = strcat(message,',0',',0');
            elseif actual_angle < desired_angle
                  message = strcat(message,',0',',',num2str(step_size)); 
            else
                  message = strcat(message,',',num2str(step_size),',0'); 
            end 
        end

% GET_DISTANCE_FROM_START Retrieves the distance in millimeters from the initial position of the inclinometer.
%
%   ret_mm = GET_DISTANCE_FROM_START(comp, s) is a function that retrieves the distance in millimeters from the initial position of the inclinometer.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       s: The serial port object for communication with the Arduino controlling the inclinometer movement.
%
%   OUTPUT:
%       ret_mm: The distance in millimeters from the initial position of the inclinometer.
%
%   This function sends a serial message to the Arduino with the 'GET_CURRENT_POSITION' command (signal 14) over the specified serial connection 's' to request the current distance from the initial position of the inclinometer.
%   The function uses the 'custom_write' function to send the serial string to the Arduino and 'custom_read' function to receive the response.
%
%   The response 'ret_mm' received from the Arduino indicates the current distance from the initial position in millimeters.
%
%   After retrieving the distance, the function flushes the serial port to discard any buffered data.

        function ret_mm = get_distance_from_start(comp, s)
            GET_CURRENT_POSITION = 14;
            serial_string = strcat(num2str(GET_CURRENT_POSITION),",0,1");
            %ret_mm = serial_communication(s, serial_string);
            custom_write(comp, s, serial_string);
            ret_mm = custom_read(comp, s);
            ret_mm = str2double(ret_mm);
            flush(s);
        end

 % Depricated not used
        function ret_sig = reset_kill_state(comp, s)
            RESET_KILL_SWITCH = 17;
            serial_string = strcat(num2str(RESET_KILL_SWITCH),",0,0");
            ret_sig = serial_communication(comp, s, serial_string);
        end

        function sig = serial_communication(comp, s, message)
            disp(message);
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
            if(str2double(sig) == 86)
                assignin('base', 'kill_switch_pressed', true);
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedDropDown.Visible = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                comp.KillSwitchPressedGo.Visible = "on";
            end
        end

% CUSTOM_WRITE Writes a message to the serial port.
%
%   CUSTOM_WRITE(comp, s, message) is a function that writes a message to the specified serial port 's'.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       s: The serial port object for communication with the Arduino.
%       message: The message to be written to the serial port.
%
%   This function flushes the serial port 's' to discard any buffered data and then writes the 'message' to the serial port using the 'writeline' function.
%   It is typically used to send serial commands or instructions to the connected Arduino.

        function custom_write(comp, s, message)
            flush(s);
            writeline(s,message);
        end

% CUSTOM_READ Reads a line of data from the serial port.
%
%   out = CUSTOM_READ(comp, s) is a function that reads a line of data from the specified serial port 's'.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       s: The serial port object for communication with the Arduino.
%
%   OUTPUT:
%       out: The line of data read from the serial port.
%
%   This function reads a line of data from the serial port 's' using the 'readline' function and returns the read data.
%   It is typically used to receive responses or data from the connected Arduino after sending a command or instruction.
%   After reading the data, the function flushes the serial port to discard any buffered data.

        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% SAVE DATA FUNCTIONS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%
% TEMP_SAVE_SINGLE_TEST Temporarily saves the data matrix of a single test to a CSV file.
%
%   TEMP_SAVE_SINGLE_TEST(comp, data_matrix, type) is a function that temporarily saves the data matrix of a single test to a CSV file.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       data_matrix: The matrix containing the test data, where each row represents a test data entry.
%       type: A string specifying the type of test (e.g., 'calibration', 'verification', etc.).
%
%   This function saves the 'data_matrix', which contains test data, to a temporary CSV file. The CSV file is stored in the 'Temp_Data_Folder' directory within the 'Ski_Flex\0_Data' folder.
%
%   Before saving, the function checks if the 'Temp_Data_Folder' exists. If not, it creates the directory using the 'mkdir' function.
%
%   The function creates a cell array 'data' by combining the 'column_names' ({'pitch', 'roll', 'forceLeft', 'forceRight'}) with the data in 'data_matrix'.
%
%   The 'writecell' function is used to write the cell array 'data' to a CSV file. The CSV file name is constructed using the 'type' input to specify the type of test.
%
%   The file is temporarily saved in the 'Temp_Data_Folder' to store data for the current session or test run. Please note that this temporary data may be overwritten or deleted during subsequent runs.

        function temp_save_single_test(comp, data_matrix, type)
            %relative_save_path = strcat('..\..\0_Data\Temp_Data_Folder\');
            current_folder = pwd;
            split_name = split(current_folder,"\Ski_Flex\");
            relative_save_path = strcat(split_name(1),"\Ski_Flex\0_Data\Temp_Data_Folder\");

            if ~exist(relative_save_path, 'dir')
               mkdir(relative_save_path);
            end
            column_names = {'pitch', 'roll', 'forceLeft', 'forceRight'};
            data = [column_names; num2cell(data_matrix)];
            writecell(data, strcat(relative_save_path, "\",type,".csv" ));    
        end

% TEMP_SAVE_DATA Temporarily saves the data matrix to a CSV file.
%
%   TEMP_SAVE_DATA(comp, data_matrix, type) is a function that temporarily saves the data matrix to a CSV file.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       data_matrix: The matrix containing the data to be saved, where each row represents a data entry.
%       type: A string specifying the type of data (e.g., 'calibration', 'verification', etc.).
%
%   This function saves the 'data_matrix' to a temporary CSV file. The CSV file is stored in the 'Temp_Data_Folder' directory within the 'Ski_Flex\0_Data' folder.
%
%   Before saving, the function checks if the 'Temp_Data_Folder' exists. If not, it creates the directory using the 'mkdir' function.
%
%   The 'writematrix' function is used to write the 'data_matrix' directly to a CSV file. The CSV file name is constructed using the 'type' input to specify the type of data.
%
%   The file is temporarily saved in the 'Temp_Data_Folder' to store data for the current session or test run. Please note that this temporary data may be overwritten or deleted during subsequent runs.

        function temp_save_data(comp, data_matrix, type)
            current_folder = pwd;
            split_name = split(current_folder,"\Ski_Flex\");
            relative_save_path = strcat(split_name(1),"\Ski_Flex\0_Data\Temp_Data_Folder\");

            if ~exist(relative_save_path, 'dir')
               mkdir(relative_save_path);
            end
            writematrix(data_matrix, strcat(relative_save_path, "\",type,".csv" ));    

        end

% CHECK_AND_ADJUST_SIZE Checks and adjusts the size of two matrices to be equal.
%
%   [m1, m2] = CHECK_AND_ADJUST_SIZE(comp, matrix1, matrix2) is a function that checks the size of two matrices and adjusts them to be equal in the number of rows.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       matrix1: The first matrix to be checked and possibly adjusted.
%       matrix2: The second matrix to be checked and possibly adjusted.
%
%   OUTPUTS:
%       m1: The adjusted first matrix.
%       m2: The adjusted second matrix.
%
%   This function checks the size of the input matrices 'matrix1' and 'matrix2' and adjusts them if necessary to have the same number of rows.
%   If 'matrix1' has more rows than 'matrix2', the function calls the 'truncate_matrix' function to remove the excess rows from 'matrix1'.
%   If 'matrix2' has more rows than 'matrix1', the function calls the 'truncate_matrix' function to remove the excess rows from 'matrix2'.
%   The 'truncate_matrix' function is used to remove rows from the matrices while preserving the specified data (if required). However, the specific details of the 'truncate_matrix' function are not provided here.
%
%   After adjusting the sizes, the matrices are returned as 'm1' and 'm2'.
%   If both matrices have the same number of rows, 'm1' and 'm2' will be the same as 'matrix1' and 'matrix2', respectively.

        function [m1, m2] = check_and_adjust_size(comp, matrix1, matrix2)%, truncate_location)
            if size(matrix1,1) > size(matrix2,1)
                matrix1 = truncate_matrix(comp, matrix1, size(matrix1,1)-size(matrix2,1));%, truncate_location);
            elseif size(matrix1,1) < size(matrix2,1)
                matrix2 = truncate_matrix(comp, matrix2, size(matrix2,1)-size(matrix1,1));%, truncate_location);
            end
            m1 = matrix1;
            m2 = matrix2;
        end

% TRUNCATE_MATRIX Truncates a matrix by removing a specified number of rows from the end.
%
%   truncated = TRUNCATE_MATRIX(comp, matrix, num_rows) is a function that truncates the input matrix by removing a specified number of rows from the end.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       matrix: The input matrix to be truncated.
%       num_rows: The number of rows to be removed from the end of the matrix.
%
%   OUTPUT:
%       truncated: The truncated matrix after removing the specified number of rows from the end.
%
%   This function truncates the input 'matrix' by removing 'num_rows' rows from the end of the matrix. The rows are removed using a loop that iterates from the last row to the last row minus 'num_rows' plus 1, and each row is deleted in reverse order.
%
%   The truncated matrix is returned as 'truncated'.
%   The function is typically used to adjust the size of a matrix when comparing or processing data sets with different numbers of rows.
%   Please note that this function modifies the input matrix directly and returns the truncated version of the same matrix.

        function truncated = truncate_matrix(comp, matrix, num_rows)%, truncate_location)
            for i = size(matrix,1):-1:size(matrix,1)-num_rows+1
                matrix(i, :) = [];
            end
            truncated = matrix;
        end

% SAVE_DATA_CLEAR_TEMP Saves data, clears temporary data, and organizes test directories.
%
%   SAVE_DATA_CLEAR_TEMP(comp, dir_name) is a function that saves data, clears temporary data, and organizes test directories.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       dir_name: The name of the directory to save the data and organize test directories.
%
%   This function performs the following tasks:
%   1. Saves data: The function constructs the 'relative_dir_path' using the specified 'dir_name' and creates the directory if it does not exist using 'mkdir'.
%   2. Organizes test directories: The function counts the number of subdirectories (test directories) in the specified directory and creates a new test directory with a name in the format "N_test" where 'N' is the count of existing test directories plus 1.
%   3. Copies temporary data: The function copies all files from the temporary data folder (in the "..\..\..\0_Data\Temp_Data_Folder\" directory) to the newly created test directory.
%   4. Clears temporary data: The function removes (deletes) the temporary data folder and creates a new empty temporary data folder using 'mkdir' for future data storage.

%   Note: The 'relative_dir_path', 'temp_folder_dir', and other directory paths should be correctly specified for proper functioning.
%   It is assumed that 'temp_folder_dir' contains temporary data files from previous tests, and data from the current test will be organized under 'dir_name' directory.
%   The 'relative_dir_path' should point to the location where data is to be saved and organized.
%   Please ensure that the input directories and paths are correctly specified and exist before using this function.

        function save_data_clear_temp(comp, dir_name)
            relative_dir_path = strcat('G:\.shortcut-targets-by-id\1jaXuO8culNZtbUZ1rrY8TEjG_TDjo2f5\Blister Labs Ski Flex\Ski_Data\', dir_name);
            if ~exist(relative_dir_path, 'dir')
               mkdir(relative_dir_path);
            end
            S = dir(relative_dir_path);
            N = nnz(~ismember({S.name},{'.','..'})&[S.isdir]);
            test_dir_name = strcat(num2str(N),"_test");
            relative_save_path = strcat(relative_dir_path,'\',test_dir_name);
            mkdir(relative_save_path);
            temp_folder_dir = strcat('..\..\..\0_Data\Temp_Data_Folder\');
            filePattern = fullfile(temp_folder_dir, '*.*');
            copyfile(filePattern, relative_save_path);
            rmdir(temp_folder_dir, 's');
            if ~exist(temp_folder_dir, 'dir')
               mkdir(temp_folder_dir);
            end
        end

% TEMP_SAVE_PLOT_AND_POINTS Temporarily saves a plot and data points to the Temp_Data_Folder directory.
%
%   TEMP_SAVE_PLOT_AND_POINTS(comp, x_points, y_points, plot_title) is a function that creates a plot, saves the plot and data points to the Temp_Data_Folder directory.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       x_points: The x-coordinate values (in millimeters) for the data points to be plotted.
%       y_points: The y-coordinate values (N*(m^2)) for the data points to be plotted.
%       plot_title: The title of the plot and the filename for saving the data points and plot image.
%
%   This function performs the following tasks:
%   1. Saves data points: The function creates a cell array 'data' by combining the 'column_names' ({"milimeters", "N*(m^2)"}) with the 'x_points' and 'y_points'.
%      The 'writecell' function is used to write the cell array 'data' to a CSV file with a filename constructed using the 'plot_title' and '_points.csv'.
%   2. Creates a plot: The function uses the 'plot' function to create a plot with 'x_points' on the x-axis and 'y_points' on the y-axis.
%   3. Sets plot properties: The function sets the title of the plot using 'plot_title', and labels the x-axis as "Milimeters" and the y-axis as "N*(m^2)".
%   4. Saves the plot image: The function saves the plot as an image (PNG) with a filename constructed using the 'plot_title' and '_graph.png'.
%
%   The data points and plot image are temporarily saved in the 'Temp_Data_Folder' to store data and visualizations for the current session or test run.
%   Please note that this temporary data may be overwritten or deleted during subsequent runs.
%   It is assumed that the 'relative_save_path' for the 'Temp_Data_Folder' is correctly specified in the function. 

        function temp_save_plot_and_points(comp, x_points, y_points, plot_title)
            relative_save_path = strcat('..\..\..\0_Data\Temp_Data_Folder\');
            if ~exist(relative_save_path, 'dir')
               mkdir(relative_save_path);
            end
            hFig = plot(x_points, y_points);
            title(plot_title);
            xlabel("Milimeters");
            ylabel("N*(m^2)");
            points_matrix = [x_points', y_points'];
            column_names = {"milimeters", "N*(m^2)"};
            data = [column_names; num2cell(points_matrix)];
            writecell(data, strcat(relative_save_path, "\",plot_title,"_points.csv" ));
            saveas(hFig, strcat(relative_save_path, "\",plot_title,"_graph.png"));
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% SENSOR READING FUNCTIONS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% GET_HWT905TTL_DATA Reads pitch and roll data from the HWT905TTL sensor through a specified serial port.
%
%   [pitch, roll] = GET_HWT905TTL_DATA(comp, port) is a function that reads pitch and roll data from the HWT905TTL sensor through the specified serial port.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       port: The serial port object for communication with the HWT905TTL sensor.
%
%   OUTPUTS:
%       pitch: The pitch angle value read from the sensor in degrees.
%       roll: The roll angle value read from the sensor in degrees.
%
%   This function continuously reads data from the HWT905TTL sensor through the specified serial 'port' until it receives the required pitch and roll data.
%   The function first reads two bytes from the serial port to check for the correct header (85) and the data type (81, 82, or 83) corresponding to pitch, roll, or yaw data, respectively.
%   When the function reads the correct data type for pitch (81) or roll (82), it reads the next three bytes as the corresponding signed 16-bit integer data values.
%   The received data is then scaled and converted to pitch and roll angles in degrees.
%   The function returns the pitch and roll angles as 'pitch' and 'roll', respectively.
%
%   Note: The HWT905TTL sensor may have specific communication protocols and data format requirements. Please ensure that the correct data type codes (81 for pitch and 82 for roll) are used to read the appropriate data from the sensor.
%   The 'port' should be correctly initialized and configured for communication with the HWT905TTL sensor before using this function.

        function [pitch, roll] = get_HWT905TTL_data(comp, port)
            f = 20;%DataFrequce
            aa=[0 0 0];
            ww=[0 0 0];
            AA = [0 0 0];
            t=0;
            tt = 0;
            a=[0 0 0]';
            w=[0 0 0]';
            A=[0 0 0]';
            while(1)
                Head = read(port,2,'uint8')';
                if (Head(1)==uint8(85))
                    switch(Head(2))
                        case 81 
                            a = read(port,3,'int16')'/32768*16 ;     
                            End = read(port,3,'uint8')';
                        case 82 
                            w = read(port,3,'int16')'/32768*2000 ;    
                            End = read(port,3,'uint8')';
                        case 83 
                            A = read(port,3,'int16')'/32768*180;
                            aa=[aa;a'];
                            ww = [ww;w'];
                            AA = [AA;A'];
                            tt = [tt;t];
                            pitch = A(1);
                            roll = A(2);
                            if (size(aa,1)>5*f)%clear history data
                                aa = aa(f:5*f,:);
                                ww = ww(f:5*f,:);
                                AA = AA(f:5*f,:);
                                tt = tt(f:5*f,:);
                            end
            
                            t=t+0.10;
                            End = read(port,3,'uint8')';
                            break
                    end    
                end
            end
            flush(port);
        end

% GET_INCLINOMETER_READING Gets updated pitch and roll readings from the HWT905TTL sensor, ensuring a different reading from the previous one.
%
%   [new_pitch, new_roll] = GET_INCLINOMETER_READING(comp, serial) is a function that gets updated pitch and roll readings from the HWT905TTL sensor, ensuring that the readings are different from the previous ones.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       serial: The serial port object for communication with the HWT905TTL sensor.
%
%   OUTPUTS:
%       new_pitch: The updated pitch angle value read from the sensor in degrees.
%       new_roll: The updated roll angle value read from the sensor in degrees.
%
%   This function calls the 'get_HWT905TTL_data' function to get the latest pitch and roll data from the HWT905TTL sensor through the specified serial port.
%   It then compares the newly obtained 'new_pitch' and 'new_roll' angles with the previous ones stored in the MATLAB workspace as 'current_pitch' and 'current_roll', respectively.
%   If the new readings are not the same as the previous ones, the 'same_point' flag is set to false, indicating that a different reading has been obtained.
%   The function attempts to get updated pitch and roll readings up to 10 times to ensure that a different reading is obtained. After that, the function assigns the latest readings to 'current_pitch' and 'current_roll' in the MATLAB workspace for subsequent comparisons.
%
%   Note: The HWT905TTL sensor may have specific communication protocols and data format requirements. The 'get_HWT905TTL_data' function is used to read pitch and roll data from the sensor, and it should be correctly configured to read the data from the sensor.
%   The 'serial' port should be correctly initialized and configured for communication with the HWT905TTL sensor before using this function.
%   The function ensures that the pitch and roll readings are updated and different from the previous readings before returning them to the calling function.

        function [new_pitch, new_roll] = get_inclinometer_reading(comp, serial)
            current_pitch = evalin("base", "current_pitch");
            current_roll = evalin("base", "current_roll");
            same_point = true;
            [new_pitch, new_roll] = get_HWT905TTL_data(comp, serial);
            count = 0;
            while same_point && count < 10
                [new_pitch, new_roll] = get_HWT905TTL_data(comp, serial);
                if ~(current_pitch == new_pitch || current_roll == new_roll)
                  same_point = false;
                end
                count = count +1;
            end
            assignin("base", "current_pitch", new_pitch);
            assignin("base", "current_roll", new_roll);
        end

% FORCE_DATA Reads force data from two sensors through specified serial ports and returns the force values.
%
%   [force1, force2] = FORCE_DATA(comp, serial1, serial2) is a function that reads force data from two sensors through the specified serial ports and returns the force values.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       serial1: The first serial port object for communication with the first sensor.
%       serial2: The second serial port object for communication with the second sensor.
%
%   OUTPUTS:
%       force1: The force value read from the first sensor.
%       force2: The force value read from the second sensor.
%
%   This function performs the following tasks for each sensor:
%   1. Queries the sensor: The function sends a query to the sensor using the 'write' function with a '?' as a string command to obtain data.
%   2. Reads the data: The function reads the data point from the sensor using the 'read' function.
%   3. Converts the data: The function removes any spaces from the read data using 'strrep', and replaces 'L' with '1' if present (as a workaround for weird data read).
%      The data is then converted to a double using 'str2double'.
%   4. Returns the force values: The converted force values are returned as 'force1' and 'force2' for the first and second sensors, respectively.
%
%   Note: The 'serial1' and 'serial2' ports should be correctly initialized and configured for communication with the corresponding sensors before using this function.
%   The function ensures that force data is obtained from each sensor by sending queries and reading the data point.
%   Please ensure that the sensor communication protocols and data format requirements are correctly implemented for proper data reading.

        function [force1,force2] = force_data(comp, serial1,serial2)
            %connects and reads first sensor
            %serial1=serialport(portname1,9600); %connects to serial port 
            write(serial1,'?','string');        %queries serial port (required to obtain data)
            force1=read(serial1,5,"string");    %reads data point
        
            %connects and read second sensor
            %serial2=serialport(portname2,9600); %connects to serial port
            write(serial2,'?','string');        %queries serial port (required to obtain data)
            force2=read(serial2,5,"string");    %reads data point
        
            %converts and outputs data for first sensor
            force1=strrep(force1,' ','');       %removes any spaces in read data
            force1=strrep(force1,'l','1');      %changes L to 1 (weird data read)
            force1=str2double(force1);          %converts data to double
        
            %converts and outputs data for second sensor
            force2=strrep(force2,' ','');       %removes any spaces in read data
            force2=strrep(force2,'l','1');      %changes L to 1 (weird data read)
            force2=str2double(force2);          %converts data to double
            flush(serial1);
            flush(serial2);
        end
        
% gets the average force data (written by fin)
% FORCE_AVERAGE Reads and averages force data from two sensors through specified serial ports.
%
%   [force1, force2] = FORCE_AVERAGE(comp, ser1, ser2, average) is a function that reads force data from two sensors through the specified serial ports and returns the averaged force values.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       ser1: The first serial port object for communication with the first sensor.
%       ser2: The second serial port object for communication with the second sensor.
%       average: The number of readings to be taken and averaged for each sensor.
%
%   OUTPUTS:
%       force1: The averaged force value read from the first sensor.
%       force2: The averaged force value read from the second sensor.
%
%   This function performs the following tasks:
%   1. Loop for averaging: The function reads force data 'average' number of times for each sensor using the 'force_data' function and stores the results in arrays 'force1' and 'force2'.
%   2. Calculate the mean: The function calculates the mean of the readings obtained for each sensor using the 'mean' function.
%   3. Returns the averaged force values: The averaged force values for the first and second sensors are returned as 'force1' and 'force2', respectively.
%
%   Note: The 'ser1' and 'ser2' ports should be correctly initialized and configured for communication with the corresponding sensors before using this function.
%   The function ensures that force data is obtained multiple times (specified by 'average') from each sensor and calculates the mean value for each sensor's readings.
%   Please ensure that the sensor communication protocols and data format requirements are correctly implemented for proper data reading.

        function [force1,force2] = force_average(comp, ser1, ser2,average)  
            for i=1:average
                [force1(i),force2(i)]=force_data(comp, ser1,ser2);
            end
            force1=mean(force1);
            force2=mean(force2);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%
        %%% CALCULATION CODE %%%
        %%%%%%%%%%%%%%%%%%%%%%%%

% GET_EI_POINTS Calculates the bending stiffness (EI) points along the ski length from data matrices of unloaded and loaded states.
%
%   [X_points, EI_points] = GET_EI_POINTS(comp, data_matrix_unloaded, data_matrix_loaded, test_interval_mm) is a function that calculates the bending stiffness (EI) points along the ski length from data matrices of unloaded and loaded states.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       data_matrix_unloaded: A matrix containing data points from the unloaded state, where each row represents a measurement and columns represent different parameters (e.g., pitch, roll, force).
%       data_matrix_loaded: A matrix containing data points from the loaded state, where each row represents a measurement and columns represent different parameters (e.g., pitch, roll, force).
%       test_interval_mm: The distance between measurements in millimeters.
%
%   OUTPUTS:
%       X_points: A column vector representing the X-coordinates (positions) along the ski length where the bending stiffness (EI) is calculated.
%       EI_points: A column vector representing the bending stiffness (EI) values calculated at different positions along the ski length.
%
%   This function performs the following tasks:
%   1. Calculate the pitch profiles: The function reads the pitch profile from the 'data_matrix_unloaded' and 'data_matrix_loaded'.
%   2. Calculate net forces: The function calculates the net forces by subtracting the unloaded forces from the loaded forces measured by strain gauges.
%   3. Set up variables and offsets: The function sets up variables for measurements and roller mass, and defines the initial offset from the applied load or clamp at the tip.
%   4. Calculate displacement in radians: The function calculates the displacement in radians from the difference between the pitch profiles of the unloaded and loaded states.
%   5. Calculate the net moment: The function calculates the net moment at different positions along the ski length using 'momentz' function, which may be defined elsewhere in the code.
%   6. Calculate the bending stiffness (EI): The function calculates the bending stiffness (EI) points using the moment and displacement values at different positions along the ski length.
%
%   Note: This function utilizes some additional functions such as 'dirTheta' and 'momentz', which are defined elsewhere in the code to perform specific calculations.
%   The 'data_matrix_unloaded' and 'data_matrix_loaded' should contain appropriate data points of pitch, roll, and forces measured during the unloaded and loaded states, respectively.
%   The test interval in millimeters 'test_interval_mm' determines the distance between the measurements for bending stiffness (EI) calculation.
%   Ensure that the necessary variables, functions, and data points are correctly defined and set up before using this function to obtain accurate EI points.

        function [X_points, EI_points] = get_EI_points(comp, data_matrix_unloaded, data_matrix_loaded, test_interval_mm)
                step_size_m = test_interval_mm/1000;
                %read the profile(unweighted pitch)
                profile = data_matrix_unloaded(:,1);
        
                %read the flex (weighted pitch)
                flex = data_matrix_loaded(:,1);
        
                %read forces from strain guages, and subtract the "unweighted" from
                %weighted to get a net force
                %force 1 is left, force 2 is right
                force1 = data_matrix_loaded(:,3)-data_matrix_unloaded(:,3);
                force2 = data_matrix_loaded(:,4)-data_matrix_unloaded(:,4);
                
                % Variables to setup Manually
                %measurements is the number of lines measured per round, = length/4
                measurements = length(flex);
        
                %rollermass = lass of rollers imparting force on skis in lbs
                rollermass = 18;
        
                %initialOffset = distance from applied load or clamp at the tip to the
                %first measurement in meters
                initialOffSet = .14 - step_size_m;
                
                %set empty string for moments
                moment = zeros(measurements,1);
                dTheta = zeros(measurements,1);
                
                % Displacement
                %displacement in radians
                displacement = zeros(1,measurements+1)';
                displacement(1:measurements) = deg2rad(profile - flex);
                assignin("base","ei_displacement",displacement);
        
                % Net Force
                %net force in lbs
                forceNet = force1+force2-rollermass;
         
                assignin("base","ei_distance_from_tip",[]);
                
                % EI calcuation
                EI = zeros(measurements,1);
                for n = 2:(measurements)
                    dTheta(n) = dirTheta(comp, n,displacement, step_size_m);
                    moment(n) = momentz(comp, n-1,forceNet,initialOffSet, measurements, test_interval_mm);
                    EI(n) = moment(n)/dTheta(n);
                end
                assignin("base", "ei_dtheta", dTheta);
                assignin("base","ei_moment",moment);
                EI_points = EI(3:measurements-1);
                X_points = linspace(0, (size(EI_points,1)-1)*test_interval_mm, size(EI_points,1));
                EI_points = EI_points';
        end

% GET_GJ_POINTS Calculates the shear stiffness (GJ) points along the ski length from data matrices of unloaded and torsion-loaded states.
%
%   [X_points, GJ_points] = GET_GJ_POINTS(comp, data_matrix_unloaded, data_matrix_torsion, test_interval_mm) is a function that calculates the shear stiffness (GJ) points along the ski length from data matrices of unloaded and torsion-loaded states.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       data_matrix_unloaded: A matrix containing data points from the unloaded state, where each row represents a measurement and columns represent different parameters (e.g., pitch, roll, force).
%       data_matrix_torsion: A matrix containing data points from the torsion-loaded state, where each row represents a measurement and columns represent different parameters (e.g., pitch, roll, force).
%       test_interval_mm: The distance between measurements in millimeters.
%
%   OUTPUTS:
%       X_points: A column vector representing the X-coordinates (positions) along the ski length where the shear stiffness (GJ) is calculated.
%       GJ_points: A column vector representing the shear stiffness (GJ) values calculated at different positions along the ski length.
%
%   This function performs the following tasks:
%   1. Calculate the pitch profiles: The function reads the pitch profile from the 'data_matrix_unloaded' and 'data_matrix_torsion'.
%   2. Calculate net forces: The function calculates the net forces by subtracting the unloaded forces from the torsion-loaded forces measured by strain gauges.
%   3. Set up variables and offsets: The function sets up variables for measurements and roller mass, and defines the initial offset from the applied load or clamp at the tip.
%   4. Calculate displacement in radians: The function calculates the displacement in radians from the difference between the pitch profiles of the unloaded and torsion-loaded states.
%   5. Calculate the net torque: The function calculates the net torque at different positions along the ski length using the difference in forces measured by strain gauges and appropriate conversion factors.
%   6. Calculate the shear stiffness (GJ): The function calculates the shear stiffness (GJ) points using the net torque and displacement values at different positions along the ski length.
%
%   Note: This function utilizes some additional functions such as 'dirTheta', which are defined elsewhere in the code to perform specific calculations.
%   The 'data_matrix_unloaded' and 'data_matrix_torsion' should contain appropriate data points of pitch, roll, and forces measured during the unloaded and torsion-loaded states, respectively.
%   The test interval in millimeters 'test_interval_mm' determines the distance between the measurements for shear stiffness (GJ) calculation.
%   Ensure that the necessary variables, functions, and data points are correctly defined and set up before using this function to obtain accurate GJ points.

        function [X_points, GJ_points] = get_GJ_points(comp, data_matrix_unloaded, data_matrix_torsion, test_interval_mm)
                step_size_m = test_interval_mm/1000;
        
                %read the profile(unweighted pitch)
                tProf = data_matrix_unloaded(:,1);
        
                %read the flex (weighted pitch)
                rotation = data_matrix_torsion(:,1);
        
                %read forces from strain guages, and subtract the "unweighted" from
                %weighted to get a net force
                %force 1 is left, force 2 is right
                force1 = data_matrix_torsion(:,3)-data_matrix_unloaded(:,3);
                force2 = data_matrix_torsion(:,4)-data_matrix_unloaded(:,4);
        
                %measurements is the number of lines measured per round, = length/4
                measurements = length(rotation);
        
                %rollermass = lass of rollers imparting force on skis in lbs
                rollermass = 18;
        
                %initialOffset = distance from applied load or clamp at the tip to the
                %first measurement in meters
                initialOffSet = .14 - step_size_m;
        
                %set empty string for moments
                moment = zeros(measurements,1);
                dTheta = zeros(measurements,1);
                EIDistanceFromTip = zeros(measurements:1);
        
                % Displacement
                %displacement in radians
                displacement = zeros(1,measurements+1)';
                displacement(1:measurements) = deg2rad(tProf - rotation);
                assignin("base","gj_displacement", displacement);
        
                % Net Torque
                %net force in lbs
                torqueNet = abs(force1-force2)*3.75/12*1.356;
        
                % GJ calcuation
                GJ = zeros(measurements,1);
                for n = 2:(measurements)
                    dTheta(n) = dirTheta(comp, n,displacement, step_size_m);
                    GJ(n) = torqueNet(n)/dTheta(n);
                end
        
                assignin("base", "gj_dtheta",dTheta);
                assignin("base", "gj_moment", moment);
                GJ_points = abs(GJ(2:measurements-1));
                X_points = linspace(0, (size(GJ_points,1)-1)*test_interval_mm, size(GJ_points,1));
                GJ_points = GJ_points';
        end
        
% MOMENTZ calculates the bending moment at a specific position along the ski length.
%
%   moment = MOMENTZ(comp, n, forceNet, initialOffSet, measurements, test_interval_mm) is a function that computes the bending moment at a particular position along the ski length during a test.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       n: The index representing the current measurement position.
%       forceNet: A vector containing the net forces calculated at different positions along the ski length.
%       initialOffSet: The initial offset from the applied load or clamp at the tip in meters.
%       measurements: The total number of measurements taken during the test.
%       test_interval_mm: The distance between measurements in millimeters.
%
%   OUTPUT:
%       moment: The bending moment at the given position in Newton-meters (Nm).
%
%   This function uses the calculated net force (forceNet) at the current position (n), and the distance from the tip to the measurement position (calculated by the function 'distanceFromTip') to compute the bending moment.
%   The formula used to calculate the moment is: moment = (forceNet(n) * distanceFromTip(comp, n, initialOffSet, measurements, test_interval_mm)) * 4.448/2;
%
%   Note: It is important to ensure that the inputs to this function are accurate and represent the relevant data points and measurements taken during the test to obtain meaningful bending moment values.

        function [moment] = momentz(comp, n, forceNet,initialOffSet,measurements,test_interval_mm)
            moment = (forceNet(n) * distanceFromTip(comp, n,initialOffSet,measurements,test_interval_mm)) *4.448/2;
        end
        
% DISTANCEFROMTIP calculates the distance from the tip of the ski to a specific measurement position.
%
%   distanceFromTip = DISTANCEFROMTIP(comp, n, initialOffSet, measurements, test_interval_mm) is a function that calculates the distance from the tip of the ski to a specific measurement position.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       n: The index representing the current measurement position.
%       initialOffSet: The initial offset from the applied load or clamp at the tip in meters.
%       measurements: The total number of measurements taken during the test.
%       test_interval_mm: The distance between measurements in millimeters.
%
%   OUTPUT:
%       distanceFromTip: The distance from the tip of the ski to the current measurement position in meters.
%
%   This function calculates the distance of the current measurement position (n) from the tip of the ski, taking into account the initial offset (initialOffSet) and the test interval (test_interval_mm) between measurements.
%   The formula used to calculate the distance from the tip is: distanceFromTip = (measurements/2 - abs(n - measurements/2)) * test_interval_mm / 1000 + initialOffSet.
%
%   Note: It is important to ensure that the inputs to this function are accurate and represent the relevant data points and measurements taken during the test to obtain meaningful distance values from the tip of the ski.

        function [distanceFromTip] = distanceFromTip(comp, n,initialOffSet,measurements,test_interval_mm)
            %the distance of the measured pitch form the applied load in inches
            ei_distance_from_tip = evalin("base", "ei_distance_from_tip");
            distanceFromTip = (measurements/2 -abs(n-measurements/2))*test_interval_mm/1000+initialOffSet;
            ei_distance_from_tip(end+1) = distanceFromTip;
            assignin("base","ei_distance_from_tip", ei_distance_from_tip);
        end
        
% DIRTHETA calculates the change in theta (displacement) at a given x location along the ski length.
%
%   dTheta = DIRTHETA(comp, n, displacement, step_size_m) is a function that calculates the change in theta (displacement) at a given x location along the ski length.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       n: The index representing the current x location.
%       displacement: A column vector containing the displacement values in radians at different x locations along the ski length.
%       step_size_m: The step size (test interval) between measurements in meters.
%
%   OUTPUT:
%       dTheta: The change in theta (displacement) at the specified x location in radians/meter.
%
%   This function calculates the change in theta (displacement) at a given x location by considering the displacement values (displacement) at three adjacent x locations: n-1, n, and n+1.
%   The formula used to calculate the change in theta is: theta = abs(displacement(n-1) - displacement(n)) + abs(displacement(n) - displacement(n+1)), and then dTheta = theta / (2 * step_size_m).
%
%   Note: It is essential to ensure that the inputs to this function are accurate and represent the relevant displacement values and step size (test interval) for meaningful dTheta calculations.
%   The displacement vector 'displacement' should be appropriately calculated using relevant data points from unloaded and torsion-loaded states before using this function.

        function [dTheta] = dirTheta(comp, n,displacement, step_size_m)
            %calcualte the change in theta (the displacement) at a given x location
            %39.37 converts inches to meters
            theta = abs(displacement(n-1)-displacement(n))+abs(displacement(n)-displacement(n+1));
            dTheta = theta/(2*step_size_m);%*39.37/4; %in radians/meter
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% HELPER FUNCTIONS FOR CALL BACKS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% RUN_UNLOADED_TEST performs the unloaded ski flex test and saves the data.
%
%   results = RUN_UNLOADED_TEST(comp) is a function that performs the unloaded ski flex test, records the data, and saves it for analysis.
%
%   INPUT:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%
%   OUTPUT:
%       results: A variable containing the test results or status of the test execution. It can be an integer, a logical value, or a string indicating the test status or results.
%
%   This function performs the following tasks:
%   1. Check the kill switch status: The function checks if the kill switch is pressed to prevent any test execution if the kill switch is active.
%   2. Set up necessary serial connections: The function establishes serial connections to the ski inclinometer, level inclinometer, Arduino, force gauges, etc.
%   3. Move the ski to the start position: The function checks the current position of the ski using the Arduino and moves it to the start position if it is not already there.
%   4. Level the ski: The function reads the pitch and roll angles from the level inclinometer and levels the ski to the desired angle (usually 0 degrees) using the Arduino.
%   5. Set the force gauges to the desired force: The function reads the forces from the force gauges and adjusts them to the desired force value using the Arduino.
%   6. Perform the ski flex test: The function performs the unloaded ski flex test by automating the measurement process at regular intervals along the ski length.
%   7. Save the test data: The function saves the recorded data as a matrix in the "data_matrix_unloaded" variable and as a CSV file in the temporary data folder.
%
%   Note: This function utilizes some additional functions defined in the code, such as 'sensor_automation', 'level_force_gauges', 'move_force_gauges_until_desired_force', 'force_average', 'get_HWT905TTL_data', 'get_distance_from_start', 'set_current_position', and 'return_to_start', to perform specific tasks in the test execution.
%   Ensure that the necessary variables, functions, and connections are correctly set up before using this function to execute the unloaded ski flex test successfully.
%   The 'comp' parameter represents the handle to the App Designer component and is used for interfacing with the GUI and user interactions during the test execution.

        function results = run_unloaded_test(comp)
            try
                KILL_SWITCH_SIGNAL = 86;
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
                assignin('base', "current_test", "unloaded");
                if ~kill_switch_pressed
                    pass = requirment_check(comp, false);
                    if ~pass
                        return;
                    end
    
                    ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                    level_inclinometer_serial = evalin("base","level_inclinometer_serial");
                    arduino_serial  = evalin("base", "arduino_serial");
                    force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                    force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
                    
                    distance_from_start = get_distance_from_start(comp, arduino_serial);
                    if distance_from_start ~= 0
                        ret_sig = return_to_start(comp, arduino_serial);
                    end
    
                    [pitch, roll] = get_HWT905TTL_data(comp, level_inclinometer_serial);
                    pitch = pitch *-1;
                    assignin("base", "current_pitch", pitch);
                    assignin("base", "current_roll", roll);
                    desired_angle = 0;
                    precision = 0.15;
                    step_size_level = 15;
                    disp(strcat("roll: ", num2str(roll)));
                    disp(strcat("pitch: ", num2str(pitch)));
                    if ~(pitch < desired_angle+precision && pitch > (desired_angle-precision))
                        disp("attempting to level force gauges");
                        ret_sig = level_force_gauges(comp, arduino_serial, desired_angle, precision, level_inclinometer_serial, step_size_level);
                    end 
                    
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    desired_force = 0;
                    precision = 0.3;
                    step_size_force = 10;
                    if force_left > desired_force+precision || force_right > desired_force+precision
                        disp("attempting to move force gauges to desired force");
                        ret_sig = move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size_force,"down", precision);
                    end
                    
                    test_interval_mm = evalin("base", "test_interval_mm");
                    direction = 0;
                    data_matrix_unloaded = sensor_automation(comp,test_interval_mm, direction);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");

                    pause(2);     
                    if (kill_switch_pressed == 0)
                        temp_save_single_test(comp,data_matrix_unloaded, "data_matrix_unloaded");
                        assignin('base','unloaded', data_matrix_unloaded);
                        % test_distance_mm = size(data_matrix_unloaded,1)*test_interval_mm;
                        % dist_between_mm = 863; %change when distance between inclinomters change

                        temp_save_single_test(comp,data_matrix_unloaded, "Unloaded");
                        sig = return_to_start(comp, arduino_serial);
                        pause(2);
                        set_current_position(comp, 0);
                    end
                else
                    ret_sig = 86;
                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   LOADED    ",m),'Run Test Error');
            end
        end

% RUN_LOADED_TEST performs the loaded ski flex test and saves the data.
%
%   result = RUN_LOADED_TEST(comp) is a function that performs the loaded ski flex test, records the data, and saves it for analysis.
%
%   INPUT:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%
%   OUTPUT:
%       result: A variable containing the test results or status of the test execution. It can be an integer, a logical value, or a string indicating the test status or results.
%
%   This function performs the following tasks:
%   1. Check the kill switch status: The function checks if the kill switch is pressed to prevent any test execution if the kill switch is active.
%   2. Set up necessary serial connections: The function establishes serial connections to the ski inclinometer, level inclinometer, Arduino, force gauges, etc.
%   3. Move the ski to the start position: The function checks the current position of the ski using the Arduino and moves it to the start position if it is not already there.
%   4. Adjust the force gauges: The function reads the forces from the force gauges and adjusts them to the desired force value using the Arduino.
%   5. Level the ski: The function reads the pitch and roll angles from the level inclinometer and levels the ski to the desired angle (usually 0 degrees) using the Arduino.
%   6. Perform the ski flex test: The function performs the loaded ski flex test by automating the measurement process at regular intervals along the ski length.
%   7. Save the test data: The function saves the recorded data as matrices in the "data_matrix_loaded" and "data_matrix_unloaded" variables and as CSV files in the temporary data folder.
%   8. Calculate EI points: The function calculates the EI (Effective Inertia) points along the ski length from the data matrices of unloaded and loaded states and saves them in the "ei_x_points" and "ei_y_points" variables.
%   9. Save EI data: The function saves the EI data (theta and moment values) as CSV files in the temporary data folder.
%   10. Display and graph EI points: The function displays the EI points on the GUI and graphs them for visual representation.
%
%   Note: This function utilizes some additional functions defined in the code, such as 'sensor_automation', 'level_force_gauges', 'move_force_gauges_until_desired_force', 'force_average', 'get_HWT905TTL_data', 'get_distance_from_start', 'set_current_position', 'return_to_start', 'check_and_adjust_size', 'get_EI_points', 'temp_save_plot_and_points', 'temp_save_data', and 'graph_ei_on_ui', to perform specific tasks in the test execution and data analysis.
%   Ensure that the necessary variables, functions, and connections are correctly set up before using this function to execute the loaded ski flex test successfully.
%   The 'comp' parameter represents the handle to the App Designer component and is used for interfacing with the GUI and user interactions during the test execution.

        function result = run_loaded_test(comp)
            try

                KILL_SWITCH_SIGNAL = 86;
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
                assignin('base', "current_test", "loaded");
                if ~kill_switch_pressed
                    pass = requirment_check(comp, true);
                    if ~pass
                        return;
                    end
    
                    ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                    level_inclinometer_serial = evalin("base","level_inclinometer_serial");
                    arduino_serial  = evalin("base", "arduino_serial");
                    force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                    force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
        
                    distance_from_start = get_distance_from_start(comp, arduino_serial);
                    if distance_from_start ~= 0
                        disp(distance_from_start);
                        disp("returning to start");
                        return_to_start(comp, arduino_serial);
                    end
        
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    desired_force = 25;
                    precision = 0.3;
                    step_size_force = 30;
                    if force_left < desired_force-precision || force_right < desired_force-precision
                        move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size_force,"up", precision);
                    end
                    %get force gauge distance from start
                    %take difference between ditance and 100 mm
                    %move up the difference
                    %pause(1);
        
                    [pitch, roll] = get_HWT905TTL_data(comp, level_inclinometer_serial);
                    pitch = pitch *-1;
                    assignin("base", "current_pitch", pitch);
                    assignin("base", "current_roll", roll);
                    desired_angle = 0;
                    precision = 0.10;
                    disp(strcat("roll: ", num2str(roll)));
                    disp(strcat("pitch: ", num2str(pitch)));
                    step_size_level = 15;
                    if ~(pitch < (desired_angle+precision) && pitch > (desired_angle-precision))
                        disp('attempting to level force gauges');
                        level_force_gauges(comp, arduino_serial, desired_angle, precision, level_inclinometer_serial, step_size_level);
                    end

                    test_interval_mm = evalin("base", "test_interval_mm");
                    direction = 0;
                    data_matrix_loaded = sensor_automation(comp, test_interval_mm, direction);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                    pause(2);
         
                    if (kill_switch_pressed == 0)
                        temp_save_single_test(comp, data_matrix_loaded, "data_matrix_loaded");

                        data_matrix_unloaded = evalin("base", "unloaded");
                        [unloaded, loaded] = check_and_adjust_size(comp,data_matrix_unloaded, data_matrix_loaded);%, truncate_from_bottom);
                        assignin("base", "loaded", loaded);

                        temp_save_single_test(comp, loaded, "Loaded");
                        [ei_x_points, ei_y_points] = get_EI_points(comp, unloaded, loaded, test_interval_mm);
                        assignin("base", "ei_x_points", ei_x_points);
                        assignin("base", "ei_y_points", ei_y_points);
                        temp_save_plot_and_points(comp, ei_x_points, ei_y_points, 'EI');
                        graph_ei_on_ui(comp, ei_x_points, ei_y_points);
                        ei_theta = evalin("base", "ei_dtheta");
                        ei_moment = evalin("base","ei_moment");
                        temp_save_data(comp, ei_theta, 'ei_theta');
                        temp_save_data(comp, ei_moment, 'ei_moment');
                        sig = return_to_start(comp, arduino_serial);
                        pause(2);
                        set_current_position(comp, 0);

                    end
                else
                    sig = 86;

                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   LOADED    ",m),'Run Test Error');
            end
        end

% RUN_TORSION_TEST performs the torsion-loaded ski flex test and saves the data.
%
%   result = RUN_TORSION_TEST(comp) is a function that performs the torsion-loaded ski flex test, records the data, and saves it for analysis.
%
%   INPUT:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%
%   OUTPUT:
%       result: A variable containing the test results or status of the test execution. It can be an integer, a logical value, or a string indicating the test status or results.
%
%   This function performs the following tasks:
%   1. Check the kill switch status: The function checks if the kill switch is pressed to prevent any test execution if the kill switch is active.
%   2. Set up necessary serial connections: The function establishes serial connections to the ski inclinometer, level inclinometer, Arduino, force gauges, etc.
%   3. Move the ski to the start position: The function checks the current position of the ski using the Arduino and moves it to the start position if it is not already there.
%   4. Adjust the force gauges: The function reads the forces from the force gauges and adjusts them to the desired force value using the Arduino.
%   5. Level the ski: The function reads the pitch and roll angles from the level inclinometer and levels the ski to the desired angle (usually 0 degrees) using the Arduino.
%   6. Perform the torsion-loaded ski flex test: The function performs the torsion-loaded ski flex test by automating the measurement process at regular intervals along the ski length.
%   7. Save the test data: The function saves the recorded data as matrices in the "data_matrix_torsion" and "data_matrix_unloaded" variables and as CSV files in the temporary data folder.
%   8. Calculate GJ points: The function calculates the shear stiffness (GJ) points along the ski length from the data matrices of unloaded and torsion-loaded states and saves them in the "gj_x_points" and "gj_y_points" variables.
%   9. Save GJ data: The function saves the GJ data (theta and moment values) as CSV files in the temporary data folder.
%   10. Display and graph GJ points: The function displays the GJ points on the GUI and graphs them for visual representation.
%   11. Move force gauges and reset: The function moves the force gauges to zero displacement and resets the position of the ski to the start position after completing the test.
%
%   Note: This function utilizes some additional functions defined in the code, such as 'sensor_automation', 'level_force_gauges', 'move_force_gauges_until_desired_force', 'force_average', 'get_HWT905TTL_data', 'get_distance_from_start', 'set_current_position', 'return_to_start', 'check_and_adjust_size', 'get_GJ_points', 'temp_save_plot_and_points', 'temp_save_data', 'graph_gj_on_ui', and 'move_force_gauges', to perform specific tasks in the test execution and data analysis.
%   Ensure that the necessary variables, functions, and connections are correctly set up before using this function to execute the torsion-loaded ski flex test successfully.
%   The 'comp' parameter represents the handle to the App Designer component and is used for interfacing with the GUI and user interactions during the test execution.

        function result = run_torsion_test(comp)
            try
                KILL_SWITCH_SIGNAL = 86;
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
                assignin('base', "current_test", "torsion");
                if ~kill_switch_pressed
                    pass = requirment_check(comp, true);
                    if ~pass
                        return;
                    end
    
                    disp("torsion");
                    ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                    level_inclinometer_serial = evalin("base","level_inclinometer_serial");
                    arduino_serial  = evalin("base", "arduino_serial");
                    force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                    force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
                    
                    distance_from_start = get_distance_from_start(comp, arduino_serial);
                    if distance_from_start ~= 0
                        return_to_start(comp, arduino_serial);
                    end
                    disp("at start")
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    desired_force = 25;
                    precision = 0.3;
                    step_size_force = 20;
                    if force_left < desired_force-precision || force_right < desired_force-precision
                        move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size_force,"up", precision);
                    end
                    %pause(1); 
                    disp("at desired force")
                    [pitch, roll] = get_HWT905TTL_data(comp, level_inclinometer_serial);
                    pitch = pitch * -1;
                    assignin("base", "current_pitch", pitch);
                    assignin("base", "current_roll", roll);
                    disp(strcat("roll: ", num2str(roll)));
                    disp(strcat("pitch: ", num2str(pitch)));
                    desired_angle = 0;
                    precision = 0.15;
                    step_size_level = 15;
                    if ~(pitch < desired_angle+precision && pitch> desired_angle-precision)
                       ret_sig = level_force_gauges(comp, arduino_serial, desired_angle, precision, level_inclinometer_serial, step_size_level);
                    end
                    disp("at desired level");
                    sig = move_force_gauges(comp, arduino_serial, 0, -20);
                    disp("at desired displacement")
                    test_interval_mm = evalin("base", "test_interval_mm");
                    direction = 0;
                    data_matrix_torsion = sensor_automation(comp,test_interval_mm, direction);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                    pause(2);
                   
                    if (kill_switch_pressed == 0)
                        data_matrix_unloaded = evalin("base", "unloaded");
                        temp_save_single_test(comp,data_matrix_torsion, "data_matrix_torsion");

                        [unloaded, torsion] = check_and_adjust_size(comp,data_matrix_unloaded, data_matrix_torsion);
                        assignin("base","torsion",torsion);

                        temp_save_single_test(comp,torsion, "Torsion");

                        [gj_x_points, gj_y_points] = get_GJ_points(comp, unloaded, torsion, test_interval_mm);
                        temp_save_plot_and_points(comp, gj_x_points, gj_y_points, 'GJ');
                        assignin("base", "gj_x_points", gj_x_points);
                        assignin("base", "gj_y_points", gj_y_points);
                        graph_gj_on_ui(comp, gj_x_points, gj_y_points);

                        gj_theta = evalin("base", "gj_dtheta");
                        gj_moment = evalin("base","gj_moment");
                        temp_save_data(comp, gj_theta, 'gj_theta');
                        temp_save_data(comp, gj_moment, 'gj_moment');

                        % move the force gauges to to 0 lbs
                        sig = move_force_gauges(comp, arduino_serial, 0, 20);
                        disp("removed displasment");
                        move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, 0, 15,"down", .3);
                        disp("moved to the force gauges to 0");
                        sig = return_to_start(comp, arduino_serial);
                        pause(2);
                        set_current_position(comp, 0);
                    end
                    disp("back at start");
                else
                    sig = 86;
                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   TROSION    ",m),'Run Test Error');
            end
            
        end

% RUN_EI_TEST performs the Equivalent Inclined (EI) ski flex test and saves the data.
%
%   result = RUN_EI_TEST(comp) is a function that performs the (EI) ski flex test, records the data, and saves it for analysis.
%
%   INPUT:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%
%   OUTPUT:
%       result: A variable containing the test results or status of the test execution. It can be an integer, a logical value, or a string indicating the test status or results.
%
%   This function performs the following tasks:
%   1. Set the current graph test: The function sets the current graph test to "ei" for tracking the test type on the GUI.
%   2. Check if unloaded data exists: The function checks if the 'unloaded' variable exists and is not empty. If unloaded data is available, it runs the loaded test directly.
%   3. Run unloaded test: If unloaded data does not exist, the function runs the unloaded test first using the 'run_unloaded_test' function.
%   4. Run loaded test: After the unloaded test is completed, the function runs the loaded test using the 'run_loaded_test' function.
%   5. Check the kill switch status: The function checks if the kill switch is pressed to pause the test execution and wait for the kill switch to be released.
%   6. Restart test after kill switch: If the test is paused due to the kill switch being pressed, the function waits for the 'KillSwitchPressedGo' button to be pressed again before resuming the test.
%   7. Repeat test after restart: If the test is restarted due to the kill switch being pressed, the function repeats the entire EI test execution, including both unloaded and loaded tests.
%
%   Note: This function utilizes some additional functions defined in the code, such as 'run_loaded_test', 'run_unloaded_test', and various GUI-related functions, to perform the(EI) ski flex test.
%   Ensure that the necessary variables, functions, and connections are correctly set up before using this function to execute the EI ski flex test successfully.
%   The 'comp' parameter represents the handle to the App Designer component and is used for interfacing with the GUI and user interactions during the test execution.

        function result = run_ei_test(comp)
            assignin('base', "current_graph_test", "ei");
            ise_unloaded = evalin( 'base', '(exist(''unloaded'',''var'') == 1 && ~isempty(unloaded))');
            if ise_unloaded
                disp("running just loaded");
                run_loaded_test(comp);
            else
                disp("running both unloaded and loaded");
                run_unloaded_test(comp);
                run_loaded_test(comp);
            end
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if kill_switch_pressed
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                %might want to block/disabe averuthing until the kill
                %switch option is slected
                disp("waiting for waitfor");
                waitfor(comp.KillSwitchPressedGo,"Enable", "off"); %edited might not be the right property.
                arduino_serial  = evalin("base", "arduino_serial");
                disp("past waitfor");
                % KillSwitchPressed;
                % uiwait(KillSwitchPressed);
            end
            restart_after_kill = evalin('base', 'restart_after_kill');
            if restart_after_kill
                disp("restarting test");
                assignin('base', "restart_after_kill", false);
                ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
                run_ei_test(comp);
            end
        end

% RUN_GJ_TEST performs the torsion ski flex test and saves the data.
%
%   result = RUN_GJ_TEST(comp) is a function that performs the torsion ski flex test, records the data, and saves it for analysis.
%
%   INPUT:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%
%   OUTPUT:
%       result: A variable containing the test results or status of the test execution. It can be an integer, a logical value, or a string indicating the test status or results.
%
%   This function performs the following tasks:
%   1. Set the current graph test: The function sets the current graph test to "gj" for tracking the test type on the GUI.
%   2. Check if unloaded data exists: The function checks if the 'unloaded' variable exists. If unloaded data is available, it runs the loaded test directly using the 'run_loaded_test' function.
%   3. Run unloaded test: If unloaded data does not exist, the function runs the unloaded test first using the 'run_unloaded_test' function.
%   4. Run torsion test: After the unloaded test is completed, the function runs the torsion test using the 'run_torsion_test' function.
%   5. Check the kill switch status: The function checks if the kill switch is pressed to pause the test execution and waits for the kill switch to be released.
%   6. Restart test after kill switch: If the test is paused due to the kill switch being pressed, the function waits for the 'KillSwitchPressedGo' button to be pressed again before resuming the test.
%   7. Repeat test after restart: If the test is restarted due to the kill switch being pressed, the function repeats the entire GJ test execution, including both unloaded and torsion tests.
%
%   Note: This function utilizes some additional functions defined in the code, such as 'run_loaded_test', 'run_unloaded_test', 'run_torsion_test', and various GUI-related functions, to perform the torsion ski flex test.
%   Ensure that the necessary variables, functions, and connections are correctly set up before using this function to execute the GJ (torsion) ski flex test successfully.
%   The 'comp' parameter represents the handle to the App Designer component and is used for interfacing with the GUI and user interactions during the test execution.

        function result = run_gj_test(comp)
            assignin('base', "current_graph_test", "gj");
            ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
            if ise_unloaded
                run_loaded_test(comp);
            else
                run_unloaded_test(comp);
                run_torsion_test(comp);
            end
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if kill_switch_pressed
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                waitfor(comp.KillSwitchPressedGo,"Enable", "off");
            end
            restart_after_kill = evalin('base', 'restart_after_kill');
            if restart_after_kill
                arduino_serial  = evalin("base", "arduino_serial");
                serial_string = strcat(num2str(17),",0,0");
                ret_sig = serial_communication(comp, arduino_serial, serial_string);
                assignin('base', "restart_after_kill", false);
                run_gj_test(comp,arduino_serial);
            end
        end

% RUN_FULL_TEST performs the complete ski flex test (unloaded, loaded, and torsion) and saves the data.
%
%   result = RUN_FULL_TEST(comp) is a function that performs the complete ski flex test, including unloaded, loaded, and torsion tests, records the data, and saves it for analysis.
%
%   INPUT:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%
%   OUTPUT:
%       result: A variable containing the test results or status of the test execution. It can be an integer, a logical value, or a string indicating the test status or results.
%
%   This function performs the following tasks:
%   1. Set the current graph test: The function sets the current graph test to "full" for tracking the test type on the GUI.
%   2. Check for existing data: The function checks for the existence of data variables for unloaded, loaded, and torsion tests using the 'exist' function.
%   3. Run loaded and torsion tests based on data availability: If unloaded data exists and neither loaded nor torsion data exists, the function runs the loaded test followed by the torsion test using the 'run_loaded_test' and 'run_torsion_test' functions, respectively.
%   4. Run torsion test: If unloaded and loaded data exist, but torsion data does not exist, the function runs the torsion test using the 'run_torsion_test' function.
%   5. Run full test sequentially: If unloaded data does not exist, the function runs the unloaded test first using the 'run_unloaded_test' function, followed by the loaded and torsion tests using 'run_loaded_test' and 'run_torsion_test', respectively.
%   6. Check the kill switch status: The function checks if the kill switch is pressed to pause the test execution and waits for the kill switch to be released.
%   7. Restart test after kill switch: If the test is paused due to the kill switch being pressed, the function waits for the 'KillSwitchPressedGo' button to be pressed again before resuming the test.
%   8. Repeat full test after restart: If the test is restarted due to the kill switch being pressed, the function repeats the entire full test execution, including all three tests (unloaded, loaded, and torsion).
%
%   Note: This function utilizes some additional functions defined in the code, such as 'run_loaded_test', 'run_unloaded_test', 'run_torsion_test', and various GUI-related functions, to perform the complete ski flex test.
%   Ensure that the necessary variables, functions, and connections are correctly set up before using this function to execute the full ski flex test successfully.
%   The 'comp' parameter represents the handle to the App Designer component and is used for interfacing with the GUI and user interactions during the test execution.
 
        function result = run_full_test(comp)
            assignin('base', "current_graph_test", "full");
            ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
            ise_loaded = evalin( 'base', 'exist(''loaded'',''var'') == 1' );
            ise_torsion = evalin( 'base', 'exist(''torsion'',''var'') == 1' );
            if ise_unloaded && ~ise_loaded && ~ise_torsion
                run_loaded_test(comp);
                disp("loaded finished");
                run_torsion_test(comp);
                disp("torsion finished");
            elseif ise_unloaded && ise_loaded && ~ise_torsion
                run_torsion_test(comp);
                disp("torsion finished");
            else
                run_unloaded_test(comp);
                disp("unloaded finished");
                run_loaded_test(comp);
                disp("loaded finished");
                run_torsion_test(comp);
                disp("torsion finished");
            end
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if kill_switch_pressed
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                waitfor(comp.KillSwitchPressedGo,"Enable", "off");
            end
            restart_after_kill = evalin('base', 'restart_after_kill');
            if restart_after_kill
                assignin('base', "restart_after_kill", false);
                run_full_test(comp);
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% GRAPHING DATA ON UI %%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%

% GRAPH_EI_ON_UI Plots the shear stiffness (GJ) data on a user interface (UI) using a bar plot and a line plot.
%
%   GRAPH_EI_ON_UI(comp, x, y) is a function that plots the shear stiffness (GJ) data on a user interface (UI) using a bar plot and a line plot. The function takes the X-coordinates (positions) along the ski length and the corresponding shear stiffness (GJ) values calculated at different positions along the ski length.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       x: A column vector representing the X-coordinates (positions) along the ski length where the shear stiffness (GJ) is calculated.
%       y: A column vector representing the shear stiffness (GJ) values calculated at different positions along the ski length.
%
%   Note: The function assumes that the 'comp' parameter refers to a valid UI figure with a designated axis component named 'EITestAxis' for plotting the data. The function clears any existing plot on the axis before plotting new data. Customize the plot appearance and other properties based on the structure of your App Designer UI.

        function result = graph_ei_on_ui(comp, x, y)
            % fig = uifigure;
            % ax = uiaxes(fig);
            % x = linspace(-pi,pi,50);
            % y = 5*sin(x);
            % plot(ax,x,y)

            bar(comp.EITestAxis,y);
            hold(comp.EITestAxis);
            plot(comp.EITestAxis,x,y);

            % comp.EITestAxis.XTick = linspace(Min(x),Max(x),10);
            % comp.EITestAxis.YTick = linspace(Min(y),Max(y),10);
            % plot(comp.EITestAxis, x, y);
        end

% GRAPH_GJ_ON_UI Plots the shear stiffness (GJ) data on a user interface (UI) using a bar plot and a line plot.
%
%   GRAPH_GJ_ON_UI(comp, x, y) is a function that plots the shear stiffness (GJ) data on a user interface (UI) using a bar plot and a line plot. The function takes the X-coordinates (positions) along the ski length and the corresponding shear stiffness (GJ) values calculated at different positions along the ski length.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       x: A column vector representing the X-coordinates (positions) along the ski length where the shear stiffness (GJ) is calculated.
%       y: A column vector representing the shear stiffness (GJ) values calculated at different positions along the ski length.
%
%   Note: The function assumes that the 'comp' parameter refers to a valid UI figure with a designated axis component named 'GJTestAxis' for plotting the data. The function clears any existing plot on the axis before plotting new data. Customize the plot appearance and other properties based on the structure of your App Designer UI.

        function result = graph_gj_on_ui(comp, x, y)
            
            bar(comp.GJTestAxis,y);
            hold(comp.GJTestAxis);
            plot(comp.GJTestAxis,x,y);

            % comp.GJTestAxis.XTick = linspace(Min(x),Max(x),10);
            % comp.GJTestAxis.YTick = linspace(Min(y),Max(y),10);
            % plot(comp.GJTestAxis, x, y);
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% CHECK FOR REQUIRMENTS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% REQUIREMENT_CHECK Checks the prerequisites before running tests.
%
%   REQUIREMENT_CHECK(comp, intermediate_test) is a function that checks the prerequisites before running tests. The function verifies whether the required variables and data are present in the base workspace before proceeding with the tests.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       intermediate_test: A boolean flag indicating whether an intermediate test is being performed. Set to true when running a loaded or torsion test after the unloaded test.
%
%   OUTPUT:
%       pass: A boolean variable indicating whether all prerequisites are met (true) or not (false).
%
%   The function checks for the following prerequisites:
%   1. 'dir_name' variable: Ensures that the directory name for saving test data is set before running tests.
%   2. 'test_interval_mm' variable: Ensures that the test interval (distance between measurements) is set before running the unloaded test.
%   3. 'unloaded' variable: When running an intermediate test (loaded or torsion), verifies that the unloaded test data is available in the base workspace.
%
%   If any of the prerequisites is missing, the function displays an alert message indicating the missing requirements and returns 'pass' as false.
%
%   Note: Customize the alert message and error handling based on the specific requirements and behavior of your App Designer UI.

        function pass = requirment_check(comp, intermediate_test)
            ise_test_interval = evalin( 'base', 'exist(''test_interval_mm'',''var'') == 1' );
            ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
            ise_dir_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
            message = "";
            pass = true;
            if ~ise_dir_name
                message = "Please set dir name before running tests";
                pass = false;
            elseif ~ise_test_interval
               message = "Please set the test interval before running unloaded test.";
               pass  = false;
            elseif intermediate_test && ~ise_unloaded
               message = "Please run unloaded test before a loaded and torsion test.";
               pass  = false;         
            end
            if ~pass
               fig = uifigure;
               uialert(fig,message,'Test Error');
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            try
                % Hiding kill switch menu when testing controller is first activated
                comp.KillSwitchPressedDropDown.Enable = "off";
                comp.KillSwitchPressedDropDown.Visible = "off";
                comp.KillSwitchPressedGo.Enable = "off";
                comp.KillSwitchPressedGo.Visible = "off";
                comp.EditField.Visible = "off";
                comp.EditField.Enable = "off";
                comp.SetNumberofTestsButton.Visible = "off";
                comp.SetNumberofTestsButton.Enable = "off";
                
                % Initialization of variables in workspace
                assignin("base", "test_saved", false);
                assignin('base', 'restart_after_kill', false);
                assignin('base', 'current_test', "none");

                % Label app title with ski that is being tested
                ise_test_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
                if ise_test_name
                    test_name = evalin("base", "dir_name");
                    comp.TestNameLabel.Text = test_name;
                else
                    comp.TestNameLabel = "";
                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   START UP    ",m),'Run Test Error');
            end
        end

        % Button pushed function: RunSelectedTestButton
        function RunSelectedTestButtonPushed(comp, event)
% RUNSELECTEDTESTBUTTONPUSHED Executes the selected test based on the user's choice in the dropdown menu.
%
%   RUNSELECTEDTESTBUTTONPUSHED(comp, event) is a function that executes the selected test based on the user's choice in the 'SelectTesttoRunDropDown' menu. The function handles the logic for running different types of tests, such as "EI Test," "GJ Test," "Full Test," and "Run Multiple Tests."
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       event: The event data for the button push event. This argument is not used in the function but is provided as a placeholder for compatibility with other button push functions.
%
%   The function performs the following tasks:
%   1. Set initial values: The function sets the 'kill_switch_pressed' and 'quit_and_clear' variables in the base workspace to 'false' to ensure that the tests start with a clean state.
%   2. Disable and hide certain UI components: The function sets the 'Enable' and 'Visible' properties of specific UI components to 'off' or 'invisible,' respectively, to prevent multiple test executions or unwanted changes during the test.
%   3. Get the selected test: The function retrieves the value of the 'SelectTesttoRunDropDown' menu to determine which test the user has chosen to run.
%   4. Execute the selected test: The function uses a switch-case statement to execute the appropriate test function based on the user's choice.
%   5. Handle "Run Multiple Tests" option: If the user selects "Run Multiple Tests," the function checks if the 'num_of_tests' variable is present in the base workspace and executes the "Full Test" function multiple times (up to the specified number of tests). It also saves the data and clears the temporary files for each test.
%   6. Display error messages: The function displays an alert message if the user has not selected any test or if the number of tests for the "Run Multiple Tests" option is not specified.
%   7. Enable the 'RunSelectedTestButton': After executing the selected test or handling multiple tests, the function re-enables the 'RunSelectedTestButton' to allow for further test selections and execution.

% Note: The behavior of this function can be customized to meet specific application requirements, and additional error handling or user feedback can be added as needed.

            assignin("base", "kill_switch_pressed", false);
            assignin("base", "quit_and_clear", false);
            comp.KillSwitchPressedDropDown.Enable = "off";
            comp.KillSwitchPressedDropDown.Visible = "off";
            comp.KillSwitchPressedGo.Enable = "off";
            comp.KillSwitchPressedGo.Visible = "off";
            comp.RunSelectedTestButton.Enable = "off";
            selected_test = convertCharsToStrings(comp.SelectTesttoRunDropDown.Value);
            disp(selected_test);
            switch selected_test
                case "EI Test"
                    run_ei_test(comp);
                case "GJ Test"
                    run_gj_test(comp);
                % case "Torsion Test"
                %     run_torsion_test(comp);
                case "Full Test"
                    run_full_test(comp);
                case "Run Multiple Tests"
                    ise_num_of_tests = evalin( 'base', 'exist(''num_of_tests'',''var'') == 1' );
                    if ise_num_of_tests
                        num_of_tests = evalin('base', "num_of_tests");
                        restart_after_kill = evalin("base", "restart_after_kill");
                        while num_of_tests > 0 && restart_after_kill == 0;
                            run_full_test(comp);
                            dir_name = evalin('base', "dir_name");
                            restart_after_kill = evalin('base', "restart_after_kill");
                            quit_and_clear = evalin('base', 'quit_and_clear');
                            if quit_and_clear == true
                                break;
                            end
                            save_data_clear_temp(comp, dir_name);
                            num_of_tests = num_of_tests -1;
                        end
                    else
                        str = "Please enter the number of tests you want to run";
                        fig = uifigure;
                        uialert(fig,str,'Test Error');
                    end

                otherwise
                   str = "Test not selected. Please select test.";
                   fig = uifigure;
                   uialert(fig,str,'Test Error');
            end
            comp.RunSelectedTestButton.Enable = "on";
        end

        % Button pushed function: SaveTestButton
        function SaveTestButtonPushed(comp, event)
            directory_name = evalin('base', 'dir_name');
            save_data_clear_temp(comp, directory_name);
            assignin("base", "test_saved", true);
            str = "The current test has been saved";
            fig = uifigure;
            uialert(fig,str,'Success Message','Icon','success');
            %if force gauges not at 0
                %then move to zero
                %move_force_gauges_until_desired_force(ardiuno_serial, force_gage1_serial, force_gage2_serial, 0, 5);
            %if inclinometer is not at start 
                %then move to start
        end

        % Callback function
        function RestartTestButtonPushed(comp, event)
            assignin('base', 'restart_after_kill', true);
        end

        % Callback function
        function QuitandClearTestButtonPushed(comp, event)
            assignin('base', 'restart_after_kill', false);
            evalin('base','clear unloaded loaded torsion');
        end

        % Value changed function: KillSwitchPressedDropDown
        function KillSwitchPressedDropDownValueChanged(comp, event)

        end

        % Button pushed function: KillSwitchPressedGo
        function KillSwitchPressedGoButtonPushed(comp, event)
% KILLSWITCHPRESSEDGOBUTTONPUSHED Handles actions when the user presses the "Go" button for the Kill Switch.
%
%   KILLSWITCHPRESSEDGOBUTTONPUSHED(comp, event) is a function that handles actions when the user presses the "Go" button for the Kill Switch. The function retrieves the value from the 'KillSwitchPressedDropDown' menu to determine the user's selection and executes different actions based on that selection. The possible actions include continuing the test after a Kill Switch interruption, restarting the test, or quitting the test and clearing the data.
%
%   INPUTS:
%       comp: A handle to the App Designer component. Pass 'app' inside the App Designer function.
%       event: The event data for the button push event. This argument is not used in the function but is provided as a placeholder for compatibility with other button push functions.
%
%   The function performs the following tasks:
%   1. Get the selected value: The function retrieves the selected value from the 'KillSwitchPressedDropDown' menu to determine the user's choice.
%   2. Execute actions based on the selected value: Using a switch-case statement, the function executes different actions based on the user's selection.
%       - "Continue Test": Sets the 'restart_after_kill' variable in the base workspace to 'true' to indicate that the test should continue after a Kill Switch interruption.
%       - "Restart Test": Sets the 'restart_after_kill' variable to 'true' and clears relevant data (depending on the type of test, e.g., "ei," "gj," or "full") from the base workspace to restart the test.
%       - "Quit and Clear Test": Sets the 'quit_and_clear' variable to 'true' to indicate that the test should be stopped and all saved test data should be cleared from the base workspace.
%   3. Set variables and visibility: The function sets various variables (e.g., 'kill_switch_pressed', 'restart_after_kill', 'quit_and_clear') to appropriate values and changes the 'Enable' and 'Visible' properties of specific UI components (e.g., 'KillSwitchPressedDropDown' and 'KillSwitchPressedGo') to 'off' or 'invisible' as needed.

% Note: The specific actions in this function can be customized to match the desired behavior and flow of the application. Additional error handling or user feedback can be added based on the application's requirements.

            value = comp.KillSwitchPressedDropDown.Value;
            arduino_serial = evalin('base', 'arduino_serial');
            switch value
                case "Continue Test"
                    assignin('base', 'restart_after_kill', true);
                case "Restart Test"
                    disp("setting restart after kill to true");
                    current_graph_test = evalin('base', "current_graph_test");
                    if current_graph_test == "ei"
                        evalin('base', strcat("clear ", "unloaded loaded"));
                    end
                    if current_graph_test == "gj"
                        evalin('base', strcat("clear ", "torsion loaded"));
                    end
                    if current_graph_test == "full"
                        evalin('base', 'clear unloaded loaded torsion');
                    end
                    assignin('base', 'restart_after_kill', true);
                case "Quit and Clear Test"
                    disp("trying to clear all saved tests");
                    assignin('base', 'restart_after_kill', false);
                    assignin('base', 'quit_and_clear', true);
                    evalin('base', 'clear unloaded loaded torsion');
                otherwise
                    str = "Test not selected. Please select test.";
                    fig = uifigure;
                    uialert(fig,str,'Test Error');
            end
            assignin('base', 'kill_switch_pressed', false);
            comp.KillSwitchPressedDropDown.Enable = "off";
            comp.KillSwitchPressedDropDown.Visible = "off";
            comp.KillSwitchPressedGo.Enable = "off";
            comp.KillSwitchPressedGo.Visible = "off";
        end

        % Button pushed function: clearDataButton
        function clearDataButtonPushed(comp, event)
            evalin("base", "clear unloaded loaded torsion");
        end

        % Value changed function: SelectTesttoRunDropDown
        function SelectTesttoRunDropDownValueChanged(comp, event)
            value = comp.SelectTesttoRunDropDown.Value;
            if value == "Run Multiple Tests"
                comp.EditField.Visible = "on";
                comp.EditField.Enable = "on";
                comp.SetNumberofTestsButton.Visible = "on";
                comp.SetNumberofTestsButton.Enable = "on";
            end
        end

        % Button pushed function: SetNumberofTestsButton
        function SetNumberofTestsButtonPushed(comp, event)
            num_of_tests = comp.EditField.Value;
            if num2str(num_of_tests) ~= "0"
                assignin('base', "num_of_tests", num_of_tests);
            else
                str = "Please type the number of tests you want to run, Cannot be 0";
                fig = uifigure;
                uialert(fig,str,'Test Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 409 722];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TestingPanel = uipanel(comp);
            comp.TestingPanel.Title = 'Testing Panel';
            comp.EITestAxis = uiaxes(comp.TestingPanel);
            title(comp.EITestAxis, 'EI Test')
            xlabel(comp.EITestAxis, 'Milimeters')
            ylabel(comp.EITestAxis, 'N*(m^2)')
            zlabel(comp.EITestAxis, 'Z')
            comp.GJTestAxis = uiaxes(comp.TestingPanel);
            title(comp.GJTestAxis, 'GJ Test')
            xlabel(comp.GJTestAxis, 'Milimeters')
            ylabel(comp.GJTestAxis, 'N*(m^2)')
            zlabel(comp.GJTestAxis, 'Z')
            comp.SelectTesttoRunDropDownLabel = uilabel(comp.TestingPanel);
            comp.SelectTesttoRunDropDownLabel.Text = 'Select Test to Run';
            comp.SelectTesttoRunDropDown = uidropdown(comp.TestingPanel);
            comp.SelectTesttoRunDropDown.Items = {'Options', 'EI Test', 'GJ Test', 'Full Test', 'Run Multiple Tests'};
            comp.SelectTesttoRunDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SelectTesttoRunDropDownValueChanged, true);
            comp.SelectTesttoRunDropDown.Placeholder = 'Options';
            comp.SelectTesttoRunDropDown.Value = 'Options';
            comp.RunSelectedTestButton = uibutton(comp.TestingPanel, 'push');
            comp.RunSelectedTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunSelectedTestButtonPushed, true);
            comp.RunSelectedTestButton.Text = 'Run Selected Test';
            comp.SaveTestButton = uibutton(comp.TestingPanel, 'push');
            comp.SaveTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveTestButtonPushed, true);
            comp.SaveTestButton.Text = 'Save Test';
            comp.TestNameLabel = uilabel(comp.TestingPanel);
            comp.TestNameLabel.Text = 'Test Name';
            comp.KillswitchpressedSelecthowtoproceedDropDownLabel = uilabel(comp.TestingPanel);
            comp.KillswitchpressedSelecthowtoproceedDropDownLabel.Text = 'Kill switch pressed. Select how to proceed. ';
            comp.KillSwitchPressedDropDown = uidropdown(comp.TestingPanel);
            comp.KillSwitchPressedDropDown.Items = {'Options', 'Restart Test', 'Quit and Clear Test', 'Continue Test'};
            comp.KillSwitchPressedDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @KillSwitchPressedDropDownValueChanged, true);
            comp.KillSwitchPressedDropDown.Value = 'Options';
            comp.KillSwitchPressedGo = uibutton(comp.TestingPanel, 'push');
            comp.KillSwitchPressedGo.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @KillSwitchPressedGoButtonPushed, true);
            comp.KillSwitchPressedGo.Text = 'Go';
            comp.clearDataButton = uibutton(comp.TestingPanel, 'push');
            comp.clearDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @clearDataButtonPushed, true);
            comp.clearDataButton.Text = 'Clear For Next Test';
            comp.SetNumberofTestsButton = uibutton(comp.TestingPanel, 'push');
            comp.SetNumberofTestsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetNumberofTestsButtonPushed, true);
            comp.SetNumberofTestsButton.Text = 'Set Number of Tests';
            comp.EditField = uieditfield(comp.TestingPanel, 'numeric');
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end