classdef setForceMotorsStart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        NavigationPanel   matlab.ui.container.Panel
        SkiTestButton     matlab.ui.control.Button
        SetStartPositionInclinometerButton  matlab.ui.control.Button
        simpleForceGauge  simpleForceGauge
        SetStartingPositionforForceGaugeMotorsLabel_2  matlab.ui.control.Label
        SetStartingPositionforForceGaugeMotorsLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)

        function sig = set_force_motors_start_pos(comp, s)
            SET_FORCE_MOTORS_CURRENT_POS = 7;
            serial_string = strcat(num2str(SET_FORCE_MOTORS_CURRENT_POS),",0,0");
            sig = serial_communication(comp, s, serial_string);
            disp(sig);
            if double2str(sig) == 7
                str = "Successfully set Force motors start position";
                fig = uifigure;
                uialert(fig,str,'Success Message','Icon','success');
            end
        end


        function sig = serial_communication(comp, s, message)
            disp(message);
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
            if(str2double(sig) == 86)
                assignin('base', 'kill_switch_pressed', true);
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedDropDown.Visible = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                comp.KillSwitchPressedGo.Visible = "on";
            end
        end
        
        function custom_write(comp, s, message)
            flush(s);
            writeline(s,message);
        end
        
        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SkiTestButton
        function SkiTestButtonPushed(app, event)
            RunTestApp;
            closereq;
        end

        % Button pushed function: SetStartPositionInclinometerButton
        function SetStartPositionInclinometerButtonPushed(app, event)
            setStartPositionInclinometer;
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetStartingPositionforForceGaugeMotorsLabel = uilabel(app.UIFigure);
            app.SetStartingPositionforForceGaugeMotorsLabel.Text = 'Set Starting Position for Force Gauge Motors';
            app.SetStartingPositionforForceGaugeMotorsLabel_2 = uilabel(app.UIFigure);
            app.SetStartingPositionforForceGaugeMotorsLabel_2.Text = 'Use Move left and Move Right to lower force gauge motors all the way to the bottom, both force gauges should read 0 or 0.2';
            app.simpleForceGauge = simpleForceGauge(app.UIFigure);
            app.NavigationPanel = uipanel(app.UIFigure);
            app.NavigationPanel.Title = 'Navigation';
            app.SetStartPositionInclinometerButton = uibutton(app.NavigationPanel, 'push');
            app.SetStartPositionInclinometerButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartPositionInclinometerButtonPushed, true);
            app.SetStartPositionInclinometerButton.Text = 'Set Start Position Inclinometer';
            app.SkiTestButton = uibutton(app.NavigationPanel, 'push');
            app.SkiTestButton.ButtonPushedFcn = createCallbackFcn(app, @SkiTestButtonPushed, true);
            app.SkiTestButton.Text = 'Ski Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setForceMotorsStart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end