classdef setStartPositionInclinometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NavigationPanel             matlab.ui.container.Panel
        NameTestButton              matlab.ui.control.Button
        SetForceMotorStartPositionButton  matlab.ui.control.Button
        SETSTARTINGPOSITIONLabel_2  matlab.ui.control.Label
        SETSTARTINGPOSITIONLabel    matlab.ui.control.Label
        InclinometerComp            InclinometerComp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetForceMotorStartPositionButton
        function SetForceMotorStartPositionButtonPushed(app, event)
            start_pos_set = evalin('base', "start_pos_set");
            try
                if start_pos_set
                    setForceMotorsStart;
                    closereq();
                else
                    fig = uifigure;
                    uialert(fig,"You must set the starting position before testing.",'Set Starting Position Error');
                end
            catch e
                m = getReport(e,"extended");
                str = strcat("failed to connect serial. \n Exception: \n ", m);
                fig = uifigure;
                uialert(fig,str,'Serial Port Connection Error');
            end
        end

        % Button pushed function: NameTestButton
        function NameTestButtonPushed(app, event)
            NameTest;
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InclinometerComp = InclinometerComp(app.UIFigure);
            app.SETSTARTINGPOSITIONLabel = uilabel(app.UIFigure);
            app.SETSTARTINGPOSITIONLabel.Text = 'SET STARTING POSITION-';
            app.SETSTARTINGPOSITIONLabel_2 = uilabel(app.UIFigure);
            app.SETSTARTINGPOSITIONLabel_2.Text = 'move ski inclinomter onto limit switch at front of machine, hit set current position';
            app.NavigationPanel = uipanel(app.UIFigure);
            app.NavigationPanel.Title = 'Navigation';
            app.SetForceMotorStartPositionButton = uibutton(app.NavigationPanel, 'push');
            app.SetForceMotorStartPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetForceMotorStartPositionButtonPushed, true);
            app.SetForceMotorStartPositionButton.Text = 'Set Force Motor Start Position';
            app.NameTestButton = uibutton(app.NavigationPanel, 'push');
            app.NameTestButton.ButtonPushedFcn = createCallbackFcn(app, @NameTestButtonPushed, true);
            app.NameTestButton.Text = 'Name Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setStartPositionInclinometer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end